classdef MAGMA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MAGMAUIFigure                  matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        HomeTab                        matlab.ui.container.Tab
        GridLayout14                   matlab.ui.container.GridLayout
        Button                         matlab.ui.control.Button
        DocumentationButton            matlab.ui.control.Button
        EditButton                     matlab.ui.control.Button
        ModelSchemeEditField           matlab.ui.control.EditField
        ModelSchemeEditFieldLabel      matlab.ui.control.Label
        SetButton                      matlab.ui.control.Button
        ListBox                        matlab.ui.control.ListBox
        ModelSubjectLabel              matlab.ui.control.Label
        EnvironmentLabel               matlab.ui.control.Label
        ReferenceLabel                 matlab.ui.control.Label
        CustomSchemeEditField          matlab.ui.control.EditField
        CustomSchemeEditFieldLabel     matlab.ui.control.Label
        AddButton                      matlab.ui.control.Button
        SearchButton                   matlab.ui.control.Button
        SubstratesLabel                matlab.ui.control.Label
        SpeciesLabel                   matlab.ui.control.Label
        EnvironmentTab                 matlab.ui.container.Tab
        GridLayout12                   matlab.ui.container.GridLayout
        PiecewiseLimsP_2               matlab.ui.container.Panel
        GridLayout20_2                 matlab.ui.container.GridLayout
        DropDown_2                     matlab.ui.control.DropDown
        RemoveLimitButton              matlab.ui.control.Button
        AddLimitButton                 matlab.ui.control.Button
        UITable3_2                     matlab.ui.control.Table
        EditField_7                    matlab.ui.control.EditField
        Label_9                        matlab.ui.control.Label
        Label_8                        matlab.ui.control.Label
        EditField_6                    matlab.ui.control.EditField
        EditField_6Label               matlab.ui.control.Label
        LimitsLabel_2                  matlab.ui.control.Label
        EnvironParamFuncL              matlab.ui.control.Label
        EnvironDD                      matlab.ui.control.DropDown
        ModelEnvironmentLabel          matlab.ui.control.Label
        GenParamDD                     matlab.ui.control.DropDown
        ParameterDropDownLabel         matlab.ui.control.Label
        SetasDefaultButton             matlab.ui.control.Button
        GenParamAB                     matlab.ui.control.Button
        GenParamUnitDD                 matlab.ui.control.DropDown
        GenParamF                      matlab.ui.control.EditField
        EnvironCustL                   matlab.ui.control.Label
        EnvironRB                      matlab.ui.control.Button
        EnvironAB                      matlab.ui.control.Button
        EnvironCustNameF               matlab.ui.control.EditField
        EnvironT                       matlab.ui.control.Table
        EnvironParamFuncPlot           matlab.ui.control.UIAxes
        SpecEnvironTab                 matlab.ui.container.Tab
        SpecEnvironGrid                matlab.ui.container.GridLayout
        MolecularWeightEditField       matlab.ui.control.NumericEditField
        MolecularWeightEditFieldLabel  matlab.ui.control.Label
        ChemRB                         matlab.ui.control.Button
        ChemT                          matlab.ui.control.Table
        ChemInitConcUnitDD             matlab.ui.control.DropDown
        ChemInitConcF                  matlab.ui.control.NumericEditField
        InitialConcentrationEditFieldLabel  matlab.ui.control.Label
        ChemNameDD                     matlab.ui.control.DropDown
        ChemicalSpceciesNameDropDownLabel  matlab.ui.control.Label
        ChemCustL                      matlab.ui.control.Label
        ChemCustNameF                  matlab.ui.control.EditField
        ChemAB                         matlab.ui.control.Button
        SpecInitConcUnitDD             matlab.ui.control.DropDown
        SpecInitConcF                  matlab.ui.control.NumericEditField
        InitialConcentrationEditField_2Label  matlab.ui.control.Label
        SpecCustL                      matlab.ui.control.Label
        SpecCustNameF                  matlab.ui.control.EditField
        SpecRB                         matlab.ui.control.Button
        SpecT                          matlab.ui.control.Table
        SpecAB                         matlab.ui.control.Button
        SpecNameDD                     matlab.ui.control.DropDown
        SpeciesDropDown_2Label         matlab.ui.control.Label
        MassTransferTab                matlab.ui.container.Tab
        GridLayout27                   matlab.ui.container.GridLayout
        LiquidGasMassTransferPanel     matlab.ui.container.Panel
        GridLayout35                   matlab.ui.container.GridLayout
        UITable5                       matlab.ui.control.Table
        ComponentDropDown_2            matlab.ui.control.DropDown
        ComponentDropDown_2Label_2     matlab.ui.control.Label
        InputFlowCompositionsandSpecificationsPanel  matlab.ui.container.Panel
        GridLayout34                   matlab.ui.container.GridLayout
        RemoveButton_4                 matlab.ui.control.Button
        AddButton_3                    matlab.ui.control.Button
        MolFractionLabel               matlab.ui.control.Label
        EditField_8                    matlab.ui.control.EditField
        PhaseDropDown                  matlab.ui.control.DropDown
        PhaseDropDownLabel             matlab.ui.control.Label
        UITable4                       matlab.ui.control.Table
        ComponentDropDown              matlab.ui.control.DropDown
        ComponentDropDownLabel_2       matlab.ui.control.Label
        InputOutputFlowsPanel          matlab.ui.container.Panel
        GridLayout33                   matlab.ui.container.GridLayout
        FlowRateEditField_4            matlab.ui.control.EditField
        FlowRateEditField_4Label       matlab.ui.control.Label
        FlowRateEditField_3            matlab.ui.control.EditField
        FlowRateEditField_3Label       matlab.ui.control.Label
        FlowRateEditField_2            matlab.ui.control.EditField
        FlowRateEditField_2Label       matlab.ui.control.Label
        DropDown3                      matlab.ui.control.DropDown
        DropDown2                      matlab.ui.control.DropDown
        DropDown_4                     matlab.ui.control.DropDown
        DropDown_3                     matlab.ui.control.DropDown
        FlowRateEditField              matlab.ui.control.EditField
        FlowRateEditFieldLabel         matlab.ui.control.Label
        GasOutCheckBox                 matlab.ui.control.CheckBox
        GasInCheckBox                  matlab.ui.control.CheckBox
        LiquidOutCheckBox              matlab.ui.control.CheckBox
        LiquidInCheckBox               matlab.ui.control.CheckBox
        SchematicPanel                 matlab.ui.container.Panel
        GridLayout28                   matlab.ui.container.GridLayout
        Image                          matlab.ui.control.Image
        GoverningFunctionsTab          matlab.ui.container.Tab
        GridLayout                     matlab.ui.container.GridLayout
        HelperFuncP                    matlab.ui.container.Panel
        GridLayout21                   matlab.ui.container.GridLayout
        HelperSaveL                    matlab.ui.control.Label
        HelperFuncL                    matlab.ui.control.Label
        HelperAB                       matlab.ui.control.Button
        Label_10                       matlab.ui.control.Label
        HelperNameF                    matlab.ui.control.EditField
        HelperSymF                     matlab.ui.control.EditField
        HelperValF                     matlab.ui.control.EditField
        Label                          matlab.ui.control.Label
        HelperRB                       matlab.ui.control.Button
        HelperUB                       matlab.ui.control.Button
        HelperDD                       matlab.ui.control.DropDown
        PiecewiseLimsP                 matlab.ui.container.Panel
        GridLayout20                   matlab.ui.container.GridLayout
        RemoveButton_3                 matlab.ui.control.Button
        AddButton_2                    matlab.ui.control.Button
        UITable3                       matlab.ui.control.Table
        EditField_5                    matlab.ui.control.EditField
        Label_3                        matlab.ui.control.Label
        DropDown                       matlab.ui.control.DropDown
        Label_2                        matlab.ui.control.Label
        EditField_4                    matlab.ui.control.EditField
        PiecewiseRangeLabel            matlab.ui.control.Label
        LimitsLabel                    matlab.ui.control.Label
        SubFuncPanel                   matlab.ui.container.Panel
        GridLayout19                   matlab.ui.container.GridLayout
        EqualsL                        matlab.ui.control.Label
        SubFuncNF                      matlab.ui.control.EditField
        SubFuncVF                      matlab.ui.control.EditField
        SubFuncDD                      matlab.ui.control.DropDown
        SubFunctionDropDownLabel       matlab.ui.control.Label
        ModelUB                        matlab.ui.control.Button
        ModelRB                        matlab.ui.control.Button
        FuncNewAB                      matlab.ui.control.Button
        FuncTypeDD                     matlab.ui.control.DropDown
        FuncComboDD                    matlab.ui.control.DropDown
        FunctionTypeDropDown_2Label    matlab.ui.control.Label
        ModelFullyDefinedNoWarningsButton  matlab.ui.control.Button
        FuncDispB                      matlab.ui.control.StateButton
        SysVarT                        matlab.ui.control.Table
        GovFuncPanel                   matlab.ui.container.Panel
        GridLayout18                   matlab.ui.container.GridLayout
        SaveL                          matlab.ui.control.Label
        GovFuncL                       matlab.ui.control.Label
        InteractCompDD                 matlab.ui.control.DropDown
        ComponentDropDownLabel         matlab.ui.control.Label
        ParametersTab                  matlab.ui.container.Tab
        GridLayout7                    matlab.ui.container.GridLayout
        ParamSymDD                     matlab.ui.control.DropDown
        ParameterSymbolDropDownLabel   matlab.ui.control.Label
        ParamNF                        matlab.ui.control.EditField
        ParameterNameEditFieldLabel    matlab.ui.control.Label
        CompParamDD                    matlab.ui.control.DropDown
        SpeciesChemicalLabel           matlab.ui.control.Label
        ParamUB                        matlab.ui.control.Button
        ParamUnitDD                    matlab.ui.control.DropDown
        ParamValF                      matlab.ui.control.NumericEditField
        ParamNumDD                     matlab.ui.control.DropDown
        ParameterNumberLabel           matlab.ui.control.Label
        GrthParamT                     matlab.ui.control.Table
        RegressionTab                  matlab.ui.container.Tab
        GridLayout32                   matlab.ui.container.GridLayout
        RegAnalysisB                   matlab.ui.control.Button
        RegSpecsB                      matlab.ui.control.Button
        VarDataUnmatchB                matlab.ui.control.Button
        RegCompParamDD                 matlab.ui.control.DropDown
        ComponentDropDown_2Label       matlab.ui.control.Label
        RegVarDataT                    matlab.ui.control.Table
        RegDVAB                        matlab.ui.control.Button
        RegDVDD                        matlab.ui.control.DropDown
        DependentVariableLabel         matlab.ui.control.Label
        ImportDataB                    matlab.ui.control.Button
        SaveAllRegParamB               matlab.ui.control.Button
        SaveRegParamDD                 matlab.ui.control.DropDown
        ParameterDropDownLabel_2       matlab.ui.control.Label
        RunRegB                        matlab.ui.control.Button
        RegParamT                      matlab.ui.control.Table
        VarDataMatchB                  matlab.ui.control.Button
        ImportedDataDD                 matlab.ui.control.DropDown
        ImportedDataDropDownLabel      matlab.ui.control.Label
        RegSysVarDD                    matlab.ui.control.DropDown
        SystemVariableDropDownLabel    matlab.ui.control.Label
        RegParamRB                     matlab.ui.control.Button
        RegParamAB                     matlab.ui.control.Button
        ParametersDropDownLabel        matlab.ui.control.Label
        SaveRegParamB                  matlab.ui.control.Button
        RegParamDD                     matlab.ui.control.DropDown
        RegPlot                        matlab.ui.control.UIAxes
        PlotCustomizationTab           matlab.ui.container.Tab
        GridLayout4                    matlab.ui.container.GridLayout
        PlotAB                         matlab.ui.control.Button
        PlotDownloadP                  matlab.ui.container.Panel
        GridLayout25                   matlab.ui.container.GridLayout
        FileTypeDD                     matlab.ui.control.DropDown
        FileTypeDropDownLabel          matlab.ui.control.Label
        SelectDirectoryB               matlab.ui.control.Button
        PlotDirL                       matlab.ui.control.Label
        PlotGenerationP                matlab.ui.container.Panel
        GridLayout24                   matlab.ui.container.GridLayout
        PlotDispTree                   matlab.ui.container.CheckBoxTree
        SelectPlotsL                   matlab.ui.control.Label
        ClosePrevPlotsCB               matlab.ui.control.CheckBox
        OptionsL                       matlab.ui.control.Label
        GenPlotB                       matlab.ui.control.Button
        PlotInitCondP                  matlab.ui.container.Panel
        GridLayout23                   matlab.ui.container.GridLayout
        AddSpecL3                      matlab.ui.control.Label
        AddSpecL2                      matlab.ui.control.Label
        AddSpecL1                      matlab.ui.control.Label
        evalUnitsDD                    matlab.ui.control.DropDown
        UnitsLabel                     matlab.ui.control.Label
        ICEvalVarDD                    matlab.ui.control.DropDown
        VariableDropDownLabel_2        matlab.ui.control.Label
        AddSpecDD3                     matlab.ui.control.DropDown
        ICEvalAxisDD                   matlab.ui.control.DropDown
        AxisDropDownLabel              matlab.ui.control.Label
        PlotICEvalSpecUB               matlab.ui.control.Button
        AddSpecDD2                     matlab.ui.control.DropDown
        AddSpecDD1                     matlab.ui.control.DropDown
        SysVarInitCondT                matlab.ui.control.Table
        AxisSpecificationsP            matlab.ui.container.Panel
        GridLayout22                   matlab.ui.container.GridLayout
        ZAxVarRB                       matlab.ui.control.Button
        ZAxVarAB                       matlab.ui.control.Button
        XAxVarRB                       matlab.ui.control.Button
        XAxVarAB                       matlab.ui.control.Button
        XAxVarL                        matlab.ui.control.Label
        ZAxVarL                        matlab.ui.control.Label
        YAxVarRB                       matlab.ui.control.Button
        YAxVarAB                       matlab.ui.control.Button
        YAxVarL                        matlab.ui.control.Label
        PlotTitleF                     matlab.ui.control.EditField
        TitleEditFieldLabel            matlab.ui.control.Label
        ZAxDispLimUB                   matlab.ui.control.Button
        YAxDispLimUB                   matlab.ui.control.Button
        XAxDispLimUB                   matlab.ui.control.Button
        Div1                           matlab.ui.control.Label
        Div2                           matlab.ui.control.Label
        Div3                           matlab.ui.control.Label
        ZAxUpDispLimDD                 matlab.ui.control.DropDown
        UpperDisplayLimitDropDown_4Label  matlab.ui.control.Label
        ZAxLoDispLimDD                 matlab.ui.control.DropDown
        LowerDisplayLimitDropDown_4Label  matlab.ui.control.Label
        YAxUpDispLimDD                 matlab.ui.control.DropDown
        UpperDisplayLimitDropDown_2Label  matlab.ui.control.Label
        YAxLoDispLimDD                 matlab.ui.control.DropDown
        LowerDisplayLimitDropDown_2Label  matlab.ui.control.Label
        XAxUpDispLimDD                 matlab.ui.control.DropDown
        UpperDisplayLimitDropDown_3Label  matlab.ui.control.Label
        XAxLoDispLimDD                 matlab.ui.control.DropDown
        LowerDisplayLimitDropDown_3Label  matlab.ui.control.Label
        ZAxDefaultRCB                  matlab.ui.control.CheckBox
        ZAxVarDD                       matlab.ui.control.DropDown
        VariableDropDown_3Label        matlab.ui.control.Label
        YAxDefaultRCB                  matlab.ui.control.CheckBox
        YAxVarDD                       matlab.ui.control.DropDown
        VariableDropDown_2Label        matlab.ui.control.Label
        XAxVarDD                       matlab.ui.control.DropDown
        VariableDropDownLabel          matlab.ui.control.Label
        NbAxesDD                       matlab.ui.control.DropDown
        NumberofAxesDropDownLabel      matlab.ui.control.Label
        XAxDefaultRCB                  matlab.ui.control.CheckBox
        ZAxTitleF                      matlab.ui.control.EditField
        ZAxisTitleEditFieldLabel       matlab.ui.control.Label
        YAxTitleF                      matlab.ui.control.EditField
        YAxisTitleLabel                matlab.ui.control.Label
        XAxTitleF                      matlab.ui.control.EditField
        XAxisTitleLabel                matlab.ui.control.Label
        PlotRB                         matlab.ui.control.Button
        PlotTitleDD                    matlab.ui.control.DropDown
        PlotsDropDownLabel             matlab.ui.control.Label
        ModelSummaryTab_3              matlab.ui.container.Tab
        GridLayout15                   matlab.ui.container.GridLayout
        GoverningFunctionsPanel        matlab.ui.container.Panel
        GridLayout26                   matlab.ui.container.GridLayout
        ModelNotCompiledLabel          matlab.ui.control.Label
        CompileModelButton             matlab.ui.control.Button
        SpecChemToggleB                matlab.ui.control.StateButton
        MSumL                          matlab.ui.control.Label
        ContextMenu2                   matlab.ui.container.ContextMenu
        Menu                           matlab.ui.container.Menu
        Menu2_2                        matlab.ui.container.Menu
    end

    
    properties (Access = public)
        Property % Description
        DataImportWindow
        RegSpecsWindow
        RegAnalysisWindow

        system = []; % Placeholder for ODESys

        plotDispTreeNodes = {};
        % structure
        % tree:
        %   name: plot_title
        %   main: uitree_node
        %       display: uitree_node
        %       download: uitree_node

        % Initial Conditions
        InitSE = 0.0028; % Initial Concentration of Synechococcus elongatus UTEX 2973, g/L
        InitMA = 0.0028; % Initial Concentration of Microcystis aeruginosa, g/L
        InitYL = 0; % Initial Concentration of Yarrowia lipolytica, g/L
        InitNO3 = 0.1235*4.4266; % Initial Concentration of Nitrate, g/L
        InitNH4 = 0; % Initial Concentration of Ammonium, g/L
        InitPO4 = 0.0533*3.0661; % Initial Concentration of Phosphate, g/L
        InitCO2 = 5E-4; % Initial Concentration of Carbon Dioxide, g/L
        InitO2 = 0; % Initial Concentration of Oxygen, g/L
        LightIntensity = 200; % Initial Light Intensity, ppf (umol photons/(m^2*s)
        InitMC = 0; % Initial Concentration of Microcystin, g/L
        InitProd = 0; % Initial Concentration of Chemical Product, g/L
        InitSOx = 0; % Initial Concentration of SOx, g/L
        InitPM25 = 0; % Initial Concentration of PM2.5, g/L
        InitPM10 = 0; % Initial Concentration of PM10, g/L

        % General Growth Parameters
        RunTime = 15*24; % Model Run Time, hrs
        Temp = 30; % Temperature, C
        Aval = 14.7; % Light Equation Coefficient, L/g
        CultureVol = 0.050; % Culture Volume, L
        
        % Species Specific Growth Parameters
        % (1) = SE
        % (2) = MA
        % (3) = YL
        muMax = [0.1,0.05,0]; % Max Growth Rates, 1/hr
        KNO3 = [0.032,0.0064,0]; % NO3^- Half Saturation Constants, g/L
        KNH4 = [1,1,1]; % NH4^+ Half Saturation Constants, g/L
        KPO4 = [0.0105,0.0021,0]; % PO4^-2 Half Saturation Constants, g/L
        KCO2 = [2.6E-4,2.6E-4,0]; % CO2 Half Saturation Constants, g/L
        KO2 = [1,1,1]; % O2 Half Saturation Constants, g/L
        KL = [14,14,0]; % Light Half Saturation Constants, ppf
        kd = [5E-5,5E-5,0]; % Death Rates, 1/hr
        YN = [0.081,0.081,0]; % Nitrogen to Biomass Conversions, gN/gBiomass
        YP = [0.0087,0.0087,0]; % Phosphorus to Biomass Conversions, gP/gBiomass
        YC = [1.8,1.8,0]; % Carbon to Biomass Conversions, gC/gBiomass
        YO = [0,0,0]; % Oxygen to Biomass Conversions, gO/gBiomass
        YT = [0,0.5,0]; % Toxin Production Rates, gToxin/gBiomass
        YProd = [0,0,0]; % Chemical Production Rates, gProd/gBiomass
        KISOx = [1,1,1]; % SOx Inhibition Constants, g/L
        KIPM25 = [1,1,1]; % PM2.5 Inhibition Constants, g/L
        KIPM10 = [1,1,1]; % PM10 Inhibition Constants, g/L
        YS = [0,0,0]; % SOx Consumption Rate, gSOx/gBiomass
        YPM25 = [0,0,0]; % PM2.5 Consumption Rate, gPM2.5/gBiomass
        YPM10 = [0,0,0]; % PM10 Consumption Rate, gPM10/gBiomass
        specParamsVals = 0; % 

        % Switches
        NO3On = true;
        NH4On = false;
        PO4On = true;
        CO2On = true;
        O2On = false;
        SOxOn = false;
        PM25On = false;
        PM10On = false;

        % Constants
        NO3toN = 4.4266; % gNO3/gN
        NH4toN = 1.2879; % gNH4/gN
        PO4toP = 3.0661; % gPO4/gP
        CO2toC = 3.6641; % gCO2/gC
        O2toO = 2; % gO2/gO

        % Miscellaneous
        envParamNames = {'Model Run Time, t';'Incident Light, I(t)';'Temperature, T(t)';'Initial Culture Volume, V';'Culture Surface Area, SA';};
    end
    
    methods (Access = private)
        
        function [t,y] = modelHandler(app)
            % Runtime
            tIn = linspace(0,app.RunTime,100);
            % Initial Conditions
            y0 = [app.InitSE, app.InitMA, app.InitYL, app.InitNO3, ...
                app.InitNH4, app.InitPO4, app.InitCO2, app.InitO2, ...
                app.InitMC, app.InitProd, app.InitSOx, app.InitPM25, ...
                app.InitPM10];
            %   Regression over ode45
            opts = odeset('RelTol',1e-6,'AbsTol',1e-6);
            [t,y] = ode45(@app.modelFunc,tIn,y0,opts);
        end

        function dydt = modelFunc(app, ~, y)
                % 1. dSE/dt
                % 2. dMA/dt
                % 3. dYL/dt
                % 4. dNO3/dt
                % 5. dNH4/dt
                % 6. dPO4/dt
                % 7. dCO2/dt
                % 8. dO2/dt
                % 9. dMC/dt
                % 10. dProd/dt
                % 11. dSOx/dt
                % 12. dPM2.5/dt
                % 13. dPM10/dt
                % I

                % Params
                Io = app.LightIntensity;
                muMax1 = app.muMax(1); muMax2 = app.muMax(2); muMax3 = app.muMax(3);
                KNO31 = app.KNO3(1); KNO32 = app.KNO3(2); KNO33 = app.KNO3(3);
                KNH41 = app.KNH4(1); KNH42 = app.KNH4(2); KNH43 = app.KNH4(3);
                KPO41 = app.KPO4(1); KPO42 = app.KPO4(2); KPO43 = app.KPO4(3);
                KCO21 = app.KCO2(1); KCO22 = app.KCO2(2); KCO23 = app.KCO2(3);
                KO21 = app.KO2(1); KO22 = app.KO2(2); KO23 = app.KO2(3);
                KL1 = app.KL(1); KL2 = app.KL(2); KL3 = app.KL(3);
                kd1 = app.kd(1); kd2 = app.kd(2); kd3 = app.kd(3);
                YN1 = app.YN(1); YN2 = app.YN(2); YN3 = app.YN(3);
                YP1 = app.YP(1); YP2 = app.YP(2); YP3 = app.YP(3);
                YC1 = app.YC(1); YC2 = app.YC(2); YC3 = app.YC(3);
                YO1 = app.YO(1); YO2 = app.YO(2); YO3 = app.YO(3);
                YT1 = app.YT(1); YT2 = app.YT(2); YT3 = app.YT(3);
                YProd1 = app.YProd(1); YProd2 = app.YProd(2); YProd3 = app.YProd(3);
                KISOx1 = app.KISOx(1); KISOx2 = app.KISOx(2); KISOx3 = app.KISOx(3);
                KIPM251 = app.KIPM25(1); KIPM252 = app.KIPM25(2); KIPM253 = app.KIPM25(3);
                KIPM101 = app.KIPM10(1); KIPM102 = app.KIPM10(2); KIPM103 = app.KIPM10(3);
                YS1 = app.YS(1); YS2 = app.YS(2); YS3 = app.YS(3);
                YPM251 = app.YPM25(1); YPM252 = app.YPM25(2); YPM253 = app.YPM25(3);
                YPM101 = app.YPM10(1); YPM102 = app.YPM10(2); YPM103 = app.YPM10(3);

                % Constants
                NO3tN = app.NO3toN;
                NH4tN = app.NH4toN;
                PO4tP = app.PO4toP;
                CO2tC = app.CO2toC;
                O2tO = app.O2toO;
                A = app.Aval;
                ktC = 70;
                ktO = 0;
                SoCO2 = 5E-4;
                SoO2 = 5E-4;
            
                % Light
                I = (Io/(A*(y(1)+y(2)+y(3))))*(1-exp(-A*(y(1)+y(2)+y(3))));
                % Add co-culture effect for nutrient equations

                % Monod Breakdown: Nutrients
                if app.NO3On == true
                    NO3 = @(KN) y(4)/(y(4)+KN);
                else
                    NO3 = @(KN) 1;
                    NO3tN = 0;
                end
                if app.NH4On == true
                    NH4 = @(KN) y(5)/(y(5)+KN);
                else
                    NH4 = @(KN) 1;
                    NH4tN = 0;
                end
                if app.PO4On == true
                    PO4 = @(KP) y(6)/(y(6)+KP);
                else
                    PO4 = @(KP) 1;
                    PO4tP = 0;
                end
                if app.CO2On == true
                    CO2 = @(KC) y(7)/(y(7)+KC);
                else
                    CO2 = @(KC) 1;
                    CO2tC = 0;
                end
                if app.O2On == true
                    O2 = @(KO) y(8)/(y(8)+KO);
                else
                    O2 = @(KO) 1;
                    O2tO = 0;
                end

                % Monod Breakdown: Inhibitors
                if app.SOxOn == true
                    SOx = @(KI) 1/(1+y(11)/KI);
                else
                    SOx = @(KI) 1;
                end
                if app.PM25On == true
                    PM25 = @(KI) 1/(1+y(12)/KI);
                else
                    PM25 = @(KI) 1;
                end
                if app.PM10On == true
                    PM10 = @(KI) 1/(1+y(13)/KI);
                else
                    PM10 = @(KI) 1;
                end
            
                % Species
                dydt(1) = muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)-kd1*y(1);
                dydt(2) = muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)-kd2*y(2);
                dydt(3) = muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3)-kd3*y(3);
                
                % Nutrients (not yet including mass transport in/out)
                dydt(4) = NO3tN*((-YN1*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YN2*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YN3*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3)));
                dydt(5) = NH4tN*((-YN1*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YN2*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YN3*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3)));
                dydt(6) = PO4tP*((-YP1*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YP2*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YP3*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3)));
                dydt(7) = ktC*(SoCO2-y(7)) - ...
                    CO2tC*((-YC1*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YC2*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YC3*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3)));
                dydt(8) = ktO*(SoO2-y(8)) - ...
                    O2tO*((-YO1*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YO2*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YO3*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3)));

                % Chemicals
                dydt(9) = YT2*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2);
                dydt(10) = YProd3*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3);

                % Inhibitors
                dydt(11) = (-YS1*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YS2*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YS3*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3));
                dydt(12) = (-YPM251*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YPM252*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YPM253*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3));
                dydt(13) = (-YPM101*muMax1*NO3(KNO31)*NH4(KNH41)*PO4(KPO41)*CO2(KCO21)*O2(KO21)*I/(I+KL1)* ...
                    SOx(KISOx1)*PM25(KIPM251)*PM10(KIPM101)*y(1)) + ...
                    (-YPM102*muMax2*NO3(KNO32)*NH4(KNH42)*PO4(KPO42)*CO2(KCO22)*O2(KO22)*I/(I+KL2)* ...
                    SOx(KISOx2)*PM25(KIPM252)*PM10(KIPM102)*y(2)) + ...
                    (-YPM103*muMax3*NO3(KNO33)*NH4(KNH43)*PO4(KPO43)*CO2(KCO23)*O2(KO23)*I/(I+KL3)* ...
                    SOx(KISOx3)*PM25(KIPM253)*PM10(KIPM103)*y(3));
        
                dydt = dydt';
        end

        function toggleSubFuncEditors(onOff)

        end

        function toggleFuncLimEditors(onOff)

        end

        function setCurrent(app)
            app.system.setCurrent(app.InteractCompDD.Value,app.SubFuncDD.Value,app.SubFuncVF.Value,app.FuncComboDD.Value,app.FuncTypeDD.Value);
        end

        function [comp,funcName,funcVal,funcCombo,funcType] = getCurrent(app)
            [comp,funcName,funcVal,funcCombo,funcType] = app.system.getCurrent();
        end

        function updateMSumL(app)
            % updates MSumL text
            if app.SpecChemToggleB.Text == "Species"
                type = "spec";
            elseif app.SpecChemToggleB.Text == "Chemicals"
                type = "chem";
            end
            compNames = app.system.getCompNamesByType(type);
            
            % update the text display for the function value in MSumL
            app.MSumL.Text = "$";
            for k=1:1:length(compNames)
                compSym = app.system.getCompSymByName(compNames{k});
                existFuncMult = app.system.getGovFuncByCompName(compNames{k},"Multiplicative","Values");
                existFuncSum = app.system.getGovFuncByCompName(compNames{k},"Additive","Values");
                for l=1:1:length(existFuncSum)
                    if existFuncSum(l) ~= ""
                        existFuncSum(l) = "+"+existFuncSum(l);
                    end
                end
                app.MSumL.Text = app.MSumL.Text + "\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1);
                if k ~= length(compNames)
                    % need to find correct syntax to create new line
                    app.MSumL.Text = app.MSumL.Text + " \\ ";
                else
                    app.MSumL.Text = app.MSumL.Text + "$";
                end
            end
        end

        function updatePlotUI(app,plot)
            app.PlotTitleF.Value = plot{1};
            if plot{3} == 2
                app.NbAxesDD.Value = app.NbAxesDD.Items{1};
            elseif plot{3} == 3
                app.NbAxesDD.Value = app.NbAxesDD.Items{2};
            end
            app.PlotDirL.Text = "Directory: "+plot{4};
            if (any(convertCharsToStrings(app.FileTypeDD.Items) == plot{5}))
                app.FileTypeDD.Value = plot{5};
            else
                app.FileTypeDD.Items = [app.FileTypeDD.Items,plot{5}];
                app.FileTypeDD.Value = plot{5};
            end
            axesNames = cell(1,length(plot{2}));
            for k=1:1:length(axesNames)
                axesNames{k} = (plot{2}{k}.title);
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
            
            % remember to update uitree for plot generation
            nodeExists = false;
            for k=1:1:length(app.plotDispTreeNodes)
                if app.plotDispTreeNodes{k}.name == plot{1}
                    app.plotDispTreeNodes{k}.main.Text = plot{1};
                    app.plotDispTreeNodes{k}.display.NodeData = plot{6};
                    app.plotDispTreeNodes{k}.download.NodeData = plot{7};
                    nodeExists = true;
                end
            end
            if ~nodeExists
                main = uitreenode(app.PlotDispTree,"Text",plot{1});
                display = uitreenode(main,"Text","Display","NodeData",plot{6});
                download = uitreenode(main,"Text","Download","NodeData",plot{7});
                app.plotDispTreeNodes{end+1} = struct('name',plot{1}, ...
                    'main',main,'display',display,'download',download);
            end
        end

        function updateAxesUI(app,axes)
            if length(axes) < 3
                app.ZAxTitleF.Value = "";
                app.ZAxVarDD.Items = {};
                app.ZAxVarDD.Value = "";
                app.ZAxLoDispLimDD.Items = {};
                app.ZAxLoDispLimDD.Value = "";
                app.ZAxUpDispLimDD.Items = {};
                app.ZAxUpDispLimDD.Value = "";
                app.ZAxDefaultRCB.Value = false;
                app.ZAxTitleF.Enable = false;
                app.ZAxVarDD.Enable = false;
                app.ZAxLoDispLimDD.Enable = false;
                app.ZAxUpDispLimDD.Enable = false;
                app.ZAxDefaultRCB.Enable = false;
                app.ZAxVarAB.Enable = false;
                app.ZAxVarRB.Enable = false;
                app.ZAxVarL.Enable = false;
                app.ZAxDispLimUB.Enable = false;
            else
                app.ZAxTitleF.Enable = true;
                app.ZAxVarDD.Enable = true;
                app.ZAxLoDispLimDD.Enable = true;
                app.ZAxUpDispLimDD.Enable = true;
                app.ZAxDefaultRCB.Enable = true;
                app.ZAxVarAB.Enable = true;
                app.ZAxVarRB.Enable = true;
                app.ZAxVarL.Enable = true;
                app.ZAxDispLimUB.Enable = true;
            end
            for k=1:1:length(axes)
                if k == 1
                    dir = "X";
                elseif k == 2
                    dir = "Y";
                elseif k == 3
                    dir = "Z";
                end
                app.(dir+"AxTitleF").Value = axes{k}{2};
                app.(dir+"AxVarDD").Items = [axes{k}{4};axes{k}{14}];
                app.(dir+"AxVarDD").Value = axes{k}{3};
                app.(dir+"AxVarL").Text = "Variable(s): $"+axes{k}{3}+"$";
                app.(dir+"AxLoDispLimDD").Items = axes{k}{15};
                app.(dir+"AxLoDispLimDD").Value = axes{k}{5};
                app.(dir+"AxUpDispLimDD").Items = axes{k}{16};
                app.(dir+"AxUpDispLimDD").Value = axes{k}{6};
                app.(dir+"AxDefaultRCB").Value = axes{k}{7};
            end
        end

        function removeRegParamsByComp(app,compName)
            params = app.system.removeRegParamByComp(compName);
            app.RegParamT.ColumnName = {'Parameter','Component','Function','Value','Regressed Value'};
            app.RegParamT.Data = params;
        end
    end

    methods (Access = public)
        function updateImportedData(app,filePath,importedData,colNames)
            app.system.setImportedData(filePath,importedData,colNames);
            
            app.ImportedDataDD.Items = string(colNames);
        end

        function updateRegSpecs(app,regSpecs)
            app.system.setRegSpecs(regSpecs);
        end

        function runNRMSE(app,normType)
            app.RegAnalysisWindow.dispNRMSE(app.system.calcNRMSE(normType));
        end

        function runParCI(app,calcType,alpha)
            app.RegAnalysisWindow.dispParCI(app.system.calcParCI(calcType,alpha));
        end

        function runPredCI(app,tval,calcType,alpha,simBnds,predIntType)
            app.RegAnalysisWindow.dispPredCI(app.system.calcPredCI(tval,calcType,alpha,simBnds,predIntType));
        end
    end

    methods (Static)
        function newItems = addItemToDD(items,newVal)
            if any(strcmp(items,newVal))
                newItems = items;
            else
                newItems = [items,newVal];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Import Statements
%             import pkg.ODESys

            % ODESys
            addpath Classes\
            app.system = ODESys;

            % Demo Code
%         app.plot(app.TestPlot,tRes,yRes(:,1:1:2));
%         xlabel(app.TestPlot,'Time (hrs)'); ylabel(app.TestPlot,'Cell Density (g/L)');
%         title(app.TestPlot, 'SE and MA');
%         xlim(app.TestPlot, [0,tRes(end)]); ylim(app.TestPlot, [0,max(yRes(:,1),yRes(:,2))]);
%         legend(app.TestPlot,{'SE';'MA'});

%             t = (1:1:100);
%             [tRes,yRes] = ode45(@(t,y) [y(1)*0.1*y(2)/(y(2)+0.15)-y(1)*0.0005; -0.081*(y(1)*0.1*y(2)/(y(2)+0.15)-y(1)*0.05)], t, [0.1,0.15]);
%             plot(app.TestAxes,tRes,yRes(:,1));
            
            % Defaults for SpeciesNutrientTable
            % Species Names
            species = {};
            % Species Numbers
            specNum = {};
            % Species Concentration Units
            speciesUnits = {};
            % Default Species Cell Concentrations
            speciesConc = {};

            % Nutrients
            nutrients = {'NO3^-';'NH4^+';'PO4^-2';'CO2';'O2';'Incident Light'};
            % Nutrients Concentration Units
            nutrientsUnits = {'g/L';'g/L';'g/L';'g/L';'g/L';'ppf'};
            % Default Nutrient Inital Concentrations
            nutrientsConc = {app.InitNO3;app.InitNH4;app.InitPO4;app.InitCO2;app.InitO2;app.LightIntensity};

            % Inhibitors
            inhibitors = {'SOx';'PM2.5';'PM10'};
            % Inhibitors Concentration Units
            inhibitorsUnits = {'g/L';'g/L';'g/L'};
            % Default Inhibitor Concentrations
            inhibitorsConc = {app.InitSOx;app.InitPM25;app.InitPM10};

            % Species Specific Growth Parameters
            specParams = {'Max Growth Rate';'NO3^- Half-Sat';'NH4^+ Half-Sat'; ...
               'PO4^-2 Half-Sat';'CO2 Half-Sat';'O2 Half-Sat';'Light Half-Sat'; ...
               'Death Const';'N-Biomass';'P-Biomass';'CO2-Biomass';'O2-Biomass';'Toxin Rate'; ...
               'Chemical Rate';'SOx Inhibition';'PM2.5 Inhibition';'PM10 Inhibition'};
            % Default Species Specific Growth Parameter Units
            specParamsUnits = {'1/hr';'g/L';'g/L';'g/L';'g/L';'g/L';'ppf'; ...
               '1/hr';'g/g';'g/g';'g/g';'g/g';'g/g';'g/g';'g/L';'g/L';'g/L'};
            % Default Species Specific Growth Parameter Values
            app.specParamsVals = {app.muMax;app.KNO3;app.KNH4;app.KPO4;app.KCO2;app.KO2;app.KL; ...
                app.kd;app.YN;app.YP;app.YC;app.YO;app.YT;app.YProd;app.KISOx;app.KIPM25;app.KIPM10};
            specParamsVals1 = cell(length(app.specParamsVals),1);
            for i = 1:1:length(app.specParamsVals)
                specParamsVals1{i,1} = app.specParamsVals{i}(1);
            end

            [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
%             [app.envParamNames,paramVals,paramUnits]
            app.EnvironT.Data = [app.envParamNames,paramVals,paramUnits];
            app.GenParamF.Value = app.EnvironT.Data{1,2};

            % Assigning default data to tables
%             app.SpecT.Data = [species,speciesUnits,speciesConc];
%             app.MainSpeciesTable.Data = [species,speciesUnits,speciesConc];

            % temporary
%             app.SpecT.Data = [specNum,species,];
%                 app.EnvironT.Data = [genParams,genParamsUnits,genParamsVals];
% 
%             app.InhibitorsTable.Data = [inhibitors,inhibitorsUnits,inhibitorsConc];
%             app.MainInhibitorsTable.Data = [inhibitors,inhibitorsUnits,inhibitorsConc;
% 
%             app.SpecGrowthParamTable.Data = [specParams,specParamsUnits,specParamsVals1];
%             app.MainSpecGrowthParamTable.Data = [specParams,specParamsUnits,specParamsVals1];
%             
%             app.GenGrowthParamTable.Data = [genParams,genParamsUnits,genParamsVals];
%             app.MainSpecGrowthParamTable.Data = [genParams,genParamsUnits,genParamsVals];
            
        end

        % Callback function
        function RunButtonPushed(app, event)
            [tRes,yRes] = app.modelHandler();
%             disp(yRes)
%             disp(tRes)
            plot(app.TestPlot,tRes,yRes(:,1:1:2));
            xlabel(app.TestPlot,'Time (hrs)'); ylabel(app.TestPlot,'Cell Density (g/L)');
            title(app.TestPlot, 'SE and MA');
            xlim(app.TestPlot, [0,tRes(end)]); ylim(app.TestPlot, [0,max(yRes(:,1),yRes(:,2))]);
            legend(app.TestPlot,{'SE';'MA'});
        end

        % Callback function
        function SpeciesDropDownValueChanged(app, event)
            speciesName = app.CompParamDD.Value;
            specParamsValsUpdated = cell(length(app.specParamsVals),1);
            if speciesName == "Synechoccocus elongatus UTEX 2973"
                for i = 1:1:length(app.specParamsVals)
                    specParamsValsUpdated{i,1} = app.specParamsVals{i}(1);
                end
            elseif speciesName == "Microcystis aeruginosa"
                for i = 1:1:length(app.specParamsVals)
                    specParamsValsUpdated{i,1} = app.specParamsVals{i}(2);
                end
            elseif speciesName == "Yarrowia lipolytico"
                for i = 1:1:length(app.specParamsVals)
                    specParamsValsUpdated{i,1} = app.specParamsVals{i}(2);
                end
            end
        end

        % Callback function
        function GrthTimeRunButtonPushed(app, event)
            [tRes,yRes] = app.modelHandler();
            plot(app.TestPlot,tRes,yRes(:,1:1:2));
            xlabel(app.TestPlot,'Time (hrs)'); ylabel(app.TestPlot,'Cell Density (g/L)');
            title(app.TestPlot, 'Cell Density vs Model Run Time');
            xlim(app.TestPlot, [0,tRes(end)]); ylim(app.TestPlot, [0,max([yRes(:,1);yRes(:,2)])]);
            legend(app.TestPlot,{'SE','MA'});
        end

        % Callback function
        function SpecParamValChanged(app, event)
            value = app.MaxGrowthRateEditField.Value;
            if event.Source.tag == "maxGrthRt"
                
            end
        end

        % Button pushed function: SpecAB
        function SpecABPushed(app, event)
            % get species name input
            specName = app.SpecNameDD.Value;
            if specName == "Custom Species"
                specName = app.SpecCustNameF.Value;
            end
            % get species initial concentration input
            specInitConc = app.SpecInitConcF.Value;
            % get species init conc unit input
            specInitConcUnit = app.SpecInitConcUnitDD.Value;

            % extracting data from table (can optimize)
            TNames = cell([height(app.SpecT.Data),1]);
            for i=1:1:length(TNames)
                TNames{i} = app.SpecT.Data{i,2};
            end

            % convert to g/L
            if specInitConcUnit == "g/L"
                specInitDispConc = specInitConc;
            elseif specInitConcUnit == "mg/L"
                specInitDispConc = specInitConc;
                specInitConc = specInitConc/(1E3);
            elseif specInitConcUnit == "ug/L"
                specInitDispConc = specInitConc;
                specInitConc = specInitConc/(1E6);
            end

            % adds new Species to display table
            if any(strcmp(TNames,specName))
                updIdx = strcmp(TNames, specName);
                % updated Species table data
                app.SpecT.Data(updIdx,:) = {char(string(length(TNames))), specName, specInitDispConc, specInitConcUnit};
                % update Species in ODESys
                app.system.updateSpecies(specName, "initConc", specInitConc);
            else
                % new Species table data
                app.SpecT.Data = [app.SpecT.Data; {char(string(length(TNames)+1)), specName, specInitDispConc, specInitConcUnit}];
                % add new Species to ODESys
                app.system.addSpecies(length(TNames)+1, specName, specInitConc);
            end

            % adds Species concentration as parameter in Interaction
            % Models Tab
            varNames = app.system.getModelVarNames();
            app.SysVarT.Data = varNames;
            app.RegSysVarDD.Items = varNames(:,2);
            regDVDDVarNames = varNames(:,2);
            app.RegDVDD.Items = regDVDDVarNames(~strcmp(regDVDDVarNames,'t'));

            % updates Species options in Models tab
            app.InteractCompDD.Items = [app.system.getSpecies('name');app.system.getChemicals('name')];
            % adds Species options in Growth Parameters tab
            app.CompParamDD.Items = varNames(:,1);
            % adds Species options in Regression tab
            app.RegCompParamDD.Items = varNames(:,1);

            % getting growth parameter data from Species
%             params = app.system.getGrthParamsByCompName(specName);
            
            app.InteractCompDDValueChanged(app.InteractCompDD);

            % ### FIXME: add functionality to update the species and chem
            % options in Plot Customization
        end

        % Value changed function: SpecNameDD
        function SpecNameDDValueChanged(app, event)
            value = app.SpecNameDD.Value;
            if value == "Custom Species"
                app.SpecCustNameF.Enable = true;
                app.SpecCustL.Enable = true;
            else
                app.SpecCustNameF.Enable = false;
                app.SpecCustL.Enable = false;
            end
        end

        % Value changed function: EnvironDD
        function EnvironDDValueChanged(app, event)
            value = app.EnvironDD.Value;
            if value == "Custom Environment"
                app.EnvironCustNameF.Enable = true;
                app.EnvironCustL.Enable = true;

                % display custom default values in table
                [paramVals, paramUnits] = app.system.getDefaultEnvironParams(true);
                app.EnvironT.Data = [app.envParamNames,paramVals,paramUnits];
            else
                app.EnvironCustNameF.Enable = false;
                app.EnvironCustL.Enable = false;

                % updates active Environment in ODESys
                app.system.setEnvironment(value);

                % display Environment values in table
                [paramVals, paramUnits] = app.system.getCurrentEnvironParams();
                app.EnvironT.Data = [app.envParamNames,paramVals,paramUnits];
            end
            app.GenParamDDValueChanged();
        end

        % Button pushed function: SpecRB
        function SpecRBButtonPushed(app, event)
            specName = app.SpecNameDD.Value;
            if specName == "Custom Species"
                specName = app.SpecCustNameF.Value;
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.SpecT.Data),1]);
            for i=1:1:length(TNames)
                TNames{i} = app.SpecT.Data{i,2};
            end

            % removes species from display table
            if any(strcmp(TNames,specName))
                rmIdx = strcmp(TNames, specName);
                app.SpecT.Data(rmIdx,:) = [];
            else
                return;
            end

            % resets numbering of Speices in table
            for i=1:1:height(app.SpecT.Data)
                app.SpecT.Data{i,1} = i;
            end

            % removing Species from ODESys
            app.system.removeSpecies(specName);

            % updates Species concentration as parameter in Interaction
            % Models Tab
            varNames = app.system.getModelVarNames();
            app.SysVarT.Data = varNames;
            app.RegSysVarDD.Items = varNames(:,2);
            regDVDDVarNames = varNames(:,2);
            app.RegDVDD.Items = regDVDDVarNames(~strcmp(regDVDDVarNames,'t'));

            % update Species options in Growth Parameters tab
            app.InteractCompDD.Items = [app.system.getSpecies('name');app.system.getChemicals('name')];
            app.CompParamDD.Items = varNames(:,1);
            app.RegCompParamDD.Items = varNames(:,1);

            app.InteractCompDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.removeRegParamsByComp(specName);
        end

        % Value changed function: GenParamDD
        function GenParamDDValueChanged(app, event)
            value = app.GenParamDD.Value;
            switch value
                case "Model Runtime (t)"
                    app.GenParamUnitDD.Items = {'days','hrs','min','sec'};
                    app.GenParamUnitDD.Value = 'hrs';
                    app.EnvironParamFuncL.Text = "$t =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{1,2});
                    cla(app.EnvironParamFuncPlot);
                    title(app.EnvironParamFuncPlot,"");
                    xlabel(app.EnvironParamFuncPlot,"");
                    ylabel(app.EnvironParamFuncPlot,"");
                case "Incident Light (I)"
                    app.GenParamUnitDD.Items = {'ppf'};
                    app.EnvironParamFuncL.Text = "$I(t) =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{2,2});
                    [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
                    I_func = matlabFunction(str2sym(paramVals{2}));
                    t_span = linspace(0,str2double(paramVals{1}),100);
                    if contains(func2str(I_func),'t')
                        plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
                    else
                        plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
                    end
                    title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
                    xlabel(app.EnvironParamFuncPlot,"Model Runtime ("+string(paramUnits{1})+")");
                    ylabel(app.EnvironParamFuncPlot,"Incident Light ("+string(paramUnits{2})+")");
                case "Temperature (T)"
                    app.GenParamUnitDD.Items = {'C','K','F','R'};
                    app.EnvironParamFuncL.Text = "$T(t) =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{3,2});
                    [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
                    T_func = matlabFunction(str2sym(paramVals{3}));
                    t_span = linspace(0,str2double(paramVals{1}),100);
                    if contains(func2str(T_func),'t')
                        plot(app.EnvironParamFuncPlot,t_span,T_func(t_span),'b-');
                    else
                        plot(app.EnvironParamFuncPlot,t_span,T_func().*ones(size(t_span)),'b-');
                    end
                    title(app.EnvironParamFuncPlot,"Temperature Function vs Model Runtime");
                    xlabel(app.EnvironParamFuncPlot,"Model Runtime ("+string(paramUnits{1})+")");
                    ylabel(app.EnvironParamFuncPlot,"Temperature ("+string(paramUnits{3})+")");
                case "Initial Culture Volume (V)"
                    app.GenParamUnitDD.Items = {'L','m^3'};
                    app.EnvironParamFuncL.Text = "$V =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{4,2});
                    cla(app.EnvironParamFuncPlot);
                    title(app.EnvironParamFuncPlot,"");
                    xlabel(app.EnvironParamFuncPlot,"");
                    ylabel(app.EnvironParamFuncPlot,"");
                case "Culture Surface Area (SA)"
                    app.GenParamUnitDD.Items = {'m^2','cm^2','km^2'};
                    app.EnvironParamFuncL.Text = "$SA =$";
                    app.GenParamF.Value = string(app.EnvironT.Data{5,2});
                    cla(app.EnvironParamFuncPlot);
                    title(app.EnvironParamFuncPlot,"");
                    xlabel(app.EnvironParamFuncPlot,"");
                    ylabel(app.EnvironParamFuncPlot,"");
            end
        end

        % Button pushed function: GenParamAB
        function GenParamABButtonPushed(app, event)
            envName = app.EnvironDD.Value;
            paramVal = app.GenParamF.Value;
            paramName = app.GenParamDD.Value;
            paramUnit = app.GenParamUnitDD.Value;            

            % 1. updating table values
            % 2. unit conversions before input to ODESys
            switch paramName
                case "Model Runtime (t)"
                    app.EnvironT.Data(1,2) = {paramVal};
                    app.EnvironT.Data(1,3) = {paramUnit};
                    switch paramUnit
                        case "days"
                            paramVal = paramVal * 24;
                        case "hrs"
                        case "min"
                            paramVal = paramVal / 60;
                        case "sec"
                            paramVal = paramVal / 3600;
                    end
                case "Incident Light (I)"
                    app.EnvironT.Data(2,2) = {paramVal};
                    app.EnvironT.Data(2,3) = {paramUnit};
                case "Temperature (T)"
                    app.EnvironT.Data(3,2) = {paramVal};
                    app.EnvironT.Data(3,3) = {paramUnit};
                    switch paramUnit
                        case "C"                            
                        case "K"
                            paramVal = paramVal - 273.15;
                        case "F"
                            paramVal = (paramVal - 32) * (5/9);
                        case "R"
                            paramVal = ((paramVal - 32) * (5/9)) - 459.67;
                    end
                case "Initial Culture Volume (V)"
                    app.EnvironT.Data(4,2) = {paramVal};
                    app.EnvironT.Data(4,3) = {paramUnit};
                    switch paramUnit
                        case "L"
                        case "m^3"
                            paramVal = paramVal * 1000;
                    end
                case "Culture Surface Area (SA)"
                    app.EnvironT.Data(5,2) = {paramVal};
                    app.EnvironT.Data(5,3) = {paramUnit};
                    switch paramUnit
                        case "m^2"
                        case "cm^2"
                            paramVal = paramVal / 1E4;
                        case "km^2"
                            paramVal = paramVal * 1E6;
                    end
            end

            app.system.updateEnvironment(envName, paramName, paramVal);

            if strcmp(paramName,"Incident Light (I)")
                    [paramVals,paramUnits,funcHandles] = app.system.getCurrentEnvironParams();
                    I_func = matlabFunction(str2sym(funcHandles{2}))
                    t_span = linspace(0,str2double(paramVals{1}),100);
                    if contains(func2str(I_func),'t')
                        plot(app.EnvironParamFuncPlot,t_span,I_func(t_span),'b-');
                    else
                        plot(app.EnvironParamFuncPlot,t_span,I_func().*ones(size(t_span)),'b-');
                    end
                    title(app.EnvironParamFuncPlot,"Incident Light Function vs Model Runtime");
                    xlabel(app.EnvironParamFuncPlot,"Model Runtime ("+string(paramUnits{1})+")");
                    ylabel(app.EnvironParamFuncPlot,"Incident Light ("+string(paramUnit)+")");
            elseif strcmp(paramName,"Temperature (T)")
                    [paramVals,paramUnits] = app.system.getCurrentEnvironParams();
                    T_func = matlabFunction(str2sym(paramVals{2}));
                    t_span = linspace(0,str2double(paramVals{1}),100);
                    if contains(func2str(T_func),'t')
                        plot(app.EnvironParamFuncPlot,t_span,T_func(t_span),'b-');
                    else
                        plot(app.EnvironParamFuncPlot,t_span,T_func().*ones(size(t_span)),'b-');
                    end
                    title(app.EnvironParamFuncPlot,"Temperature Function vs Model Runtime");
                    xlabel(app.EnvironParamFuncPlot,"Model Runtime ("+string(paramUnits{1})+")");
                    ylabel(app.EnvironParamFuncPlot,"Temperature ("+string(paramUnit)+")");
            end
        end

        % Button pushed function: EnvironAB
        function EnvironABButtonPushed(app, event)
            newEnvName = app.EnvironCustNameF.Value;
            % add Environment option to EnvironDD
            custEnvItem = app.EnvironDD.Items(end);
            newEnvItems = [app.EnvironDD.Items(1:end-1),{newEnvName},custEnvItem];
            app.EnvironDD.Items = newEnvItems;
            % set EnvironDD to new Custom Environment
            app.EnvironDD.Value = newEnvName;
            % clear custom Environment input field
            app.EnvironCustNameF.Value = '';
            % add Environment to ODESys
            app.system.addEnvironment(newEnvName);
        end

        % Button pushed function: EnvironRB
        function EnvironRBButtonPushed(app, event)
            envName = app.EnvironDD.Value;

            % removes Environment from dropdown
            rmIdx = strcmp(app.EnvironDD.Items,envName);
            app.EnvironDD.Items(rmIdx) = [];
            
            % switch to different Environment in table
            app.EnvironDD.Value = app.EnvironDD.Items(1);
            
            % remove Environment from ODESys
            app.system.removeEnvironment(envName);
        end

        % Button pushed function: ChemAB
        function ChemABPushed(app, event)
            % get chemical name input
            chemName = app.ChemNameDD.Value;
            if chemName == "Custom Chemical"
                chemName = app.ChemCustNameF.Value;
            end
            % get chemical initial concentration input
            chemInitConc = app.ChemInitConcF.Value;
            % get chemical init conc unit input
            chemInitConcUnit = app.ChemInitConcUnitDD.Value;

            % extracting data from table (can optimize)
            TNames = cell([height(app.ChemT.Data),1]);
            for i=1:1:length(TNames)
                TNames{i} = app.ChemT.Data{i,2};
            end

            % convert to g/L
            if chemInitConcUnit == "g/L"
                chemInitDispConc = chemInitConc;
            elseif chemInitConcUnit == "mg/L"
                chemInitDispConc = chemInitConc;
                chemInitConc = chemInitConc/(1E3);
            elseif chemInitConcUnit == "ug/L"
                chemInitDispConc = chemInitConc;
                chemInitConc = chemInitConc/(1E6);
            end

            % adds new Chemical to display table
            if any(strcmp(TNames,chemName))
                % updated Chemical table data
                updIdx = strcmp(TNames, chemName);
                app.ChemT.Data(updIdx,:) = {char(string(length(TNames))), chemName, chemInitDispConc, chemInitConcUnit};
                % update Chemical in ODESys
                app.system.updateChemical(chemName, "initConc", chemInitConc);
            else
                % new Chemical table data
                app.ChemT.Data = [app.ChemT.Data; {char(string(length(TNames)+1)), chemName, chemInitDispConc, chemInitConcUnit}];
                % add new Chemical to ODESys
                app.system.addChemical(length(TNames)+1, chemName, chemInitConc);
            end

            % adds Species concentration as parameter in Interaction
            % Models Tab
            varNames = app.system.getModelVarNames();
            app.SysVarT.Data = varNames;
            app.RegSysVarDD.Items = varNames(:,2);
            regDVDDVarNames = varNames(:,2);
            app.RegDVDD.Items = regDVDDVarNames(~strcmp(regDVDDVarNames,'t'));

            % updates Chemical names in the Interactions and Params DDs
            app.InteractCompDD.Items = [app.system.getSpecies('name');app.system.getChemicals('name')];
            app.CompParamDD.Items = varNames(:,1);
            app.RegCompParamDD.Items = varNames(:,1);

            app.InteractCompDDValueChanged();
        end

        % Button pushed function: ChemRB
        function ChemRBButtonPushed(app, event)
            chemName = app.ChemNameDD.Value;
            if chemName == "Custom Chemical"
                chemName = app.ChemCustNameF.Value;
            end

            % extracting data from table (can optimize)
            TNames = cell([height(app.ChemT.Data),1]);
            for i=1:1:length(TNames)
                TNames{i} = app.ChemT.Data{i,2};
            end

            % removes chemical from display table
            if any(strcmp(TNames,chemName))
                rmIdx = strcmp(TNames, chemName);
                app.ChemT.Data(rmIdx,:) = [];
            else
                return;
            end

            % resets numbering of Chemical in table
            for i=1:1:height(app.ChemT.Data)
                app.ChemT.Data{i,1} = i;
            end

            % removing Chemical from ODESys
            app.system.removeChemical(chemName);

            % adding chemical names to Interaction and Param DDs
            varNames = app.system.getModelVarNames();
            app.SysVarT.Data = varNames;
            app.RegSysVarDD.Items = varNames(:,2);
            regDVDDVarNames = varNames(:,2);
            app.RegDVDD.Items = regDVDDVarNames(~strcmp(regDVDDVarNames,'t'));

            % updates Chemical names in the Interactions and Params DDs
            app.InteractCompDD.Items = [app.system.getSpecies('name');app.system.getChemicals('name')];
            app.CompParamDD.Items = varNames(:,1);
            app.RegCompParamDD.Items = varNames(:,1);

            app.InteractCompDDValueChanged();
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();

            app.removeRegParamsByComp(chemName);
        end

        % Value changed function: ChemNameDD
        function ChemNameDDValueChanged(app, event)
            value = app.ChemNameDD.Value;
            if value == "Custom Chemical"
                app.ChemCustNameF.Enable = true;
                app.ChemCustL.Enable = true;
            else
                app.ChemCustNameF.Enable = false;
                app.ChemCustL.Enable = false;
            end
        end

        % Value changed function: CompParamDD
        function CompParamDDValueChanged(app, event)
            compName = app.CompParamDD.Value;

            compNames = [app.system.getCompNamesByType("spec");app.system.getCompNamesByType("chem")];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                % getting growth parameter data from Species
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end
            
            % updating the data in GrthParamT
            app.GrthParamT.Data = params;
            app.ParamNumDD.Items = params(:,1);
            app.ParamSymDD.Items = params(:,4);
            if length(app.ParamNumDD.Items) > 0 %#ok<ISMT> 
                app.ParamNumDD.Value = app.ParamNumDD.Items(1);
            end
            if length(app.ParamSymDD.Items) > 0 %#ok<ISMT>
                app.ParamSymDD.Value = app.ParamSymDD.Items(1);
            end
%             idx = convertCharsToStrings(app.GrthParamT.Data(:,4)) == convertCharsToStrings(params(:,4))
%             test = app.GrthParamT.Data
            % ### FIXMEUI: try and catch
            try
                app.ParamNF.Value = app.GrthParamT.Data{1,3};
                app.ParamValF.Value = app.GrthParamT.Data{1,5};
                app.ParamUnitDD.Value = app.GrthParamT.Data{1,6};
            catch err
                disp(err);
            end
        end

        % Button pushed function: ParamUB
        function ParamUBPushed(app, event)
            compName = app.CompParamDD.Value;
            paramSym = app.ParamSymDD.Value;
            paramNum = str2double(app.ParamNumDD.Value);
            newParamName = app.ParamNF.Value;
            newParamVal = app.ParamValF.Value;
            newParamUnit = app.ParamUnitDD.Value;
            
            compNames = [app.system.getCompNamesByType("spec");app.system.getCompNamesByType("chem")];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                funcName = app.GrthParamT.Data{paramNum,2};
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                funcName = app.CompParamDD.Value;
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                funcName = app.CompParamDD.Value;
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            if ~any(strcmp([app.ParamUnitDD.Items{:}],newParamUnit))
                app.ParamUnitDD.Items = [app.ParamUnitDD.Items,{newParamUnit}];
            end

            % updating the data in GrthParamT
            app.GrthParamT.Data = params;
        end

        % Value changed function: ParamSymDD
        function ParamSymDDValueChanged(app, event)
            paramSym = app.ParamSymDD.Value;
            % ### FIXME: if 2 params of the same name, won't automatically
            % adjust editor values when the parameter symbol is changed
            idx = convertCharsToStrings(app.GrthParamT.Data(:,4)) == convertCharsToStrings(paramSym);
            app.ParamNumDD.Value = app.ParamNumDD.Items{idx};
            app.ParamNF.Value = app.GrthParamT.Data{idx,3};
            app.ParamValF.Value = app.GrthParamT.Data{idx,5};
            app.ParamUnitDD.Value = app.GrthParamT.Data{idx,6};

%             idx = convertCharsToStrings(app.GrthParamT.Data(:,4)) == convertCharsToStrings(paramSym)
        end

        % Value changed function: ParamNumDD
        function ParamNumDDValueChanged(app, event)
            paramNum = app.ParamNumDD.Value;
            idx = [app.GrthParamT.Data{:,1}] == paramNum;
            app.ParamSymDD.Value = app.ParamSymDD.Items{idx};
            app.ParamNF.Value = app.GrthParamT.Data{idx,3};
            app.ParamValF.Value = app.GrthParamT.Data{idx,5};
            app.ParamUnitDD.Value = app.GrthParamT.Data{idx,6};

%             idx = convertCharsToStrings(app.GrthParamT.Data(:,4)) == convertCharsToStrings(paramSym)
        end

        % Value changed function: InteractCompDD
        function InteractCompDDValueChanged(app, event)
            compName = app.InteractCompDD.Value;
            funcCombo = app.FuncComboDD.Value;
            % Updates the equation of the given component, the subfunction
            % input options, and the function limits
            app.SubFuncDD.Items = app.system.getCompFuncNames(compName,funcCombo);

            if app.FuncDispB.Value
                valsOrNames = "Values";
            else
                valsOrNames = "Names";
            end

            % update the displayed governing function
            compSym = app.system.getCompSymByName(compName);
            existFuncMult = app.system.getGovFuncByCompName(compName,"Multiplicative",valsOrNames);
            existFuncSum = app.system.getGovFuncByCompName(compName,"Additive",valsOrNames);
            for k=1:1:length(existFuncSum)
                if existFuncSum ~= ""
                    existFuncSum = "+"+existFuncSum;
                end
            end
            app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1);

            app.FuncComboDDValueChanged();

            app.CompParamDD.Value = compName;
            app.CompParamDDValueChanged();
            app.RegCompParamDDValueChanged();
        end

        % Button pushed function: ModelUB
        function ModelUBButtonPushed(app, event)
            compName = app.InteractCompDD.Value;
            funcVal = app.SubFuncVF.Value;
            funcName = app.SubFuncNF.Value;
            funcCombo = app.FuncComboDD.Value;
            funcType = app.FuncTypeDD.Value;

            % save the new function to the component
            app.system.updateModel(compName,funcVal,funcName,funcCombo,funcType);

            % set governing func saved status to "Saved"
            app.SaveL.Text = "Saved";

            % enables relevant editors
            app.SubFuncDD.Enable = true;
            app.ModelRB.Enable = true;
            app.FuncComboDD.Enable = true;
            app.FuncTypeDD.Enable = true;
            app.FuncNewAB.Enable = true;
            app.SubFuncDD.Enable = true;
            app.SubFuncNF.Enable = true;
            app.SubFuncVF.Enable = true;

            % disables ModelUB, reenable when there is change
            app.ModelUB.Enable = false;

            % update current
            app.setCurrent();

            % getting growth parameter data from Species
            params = app.system.getGrthParamsByCompName(compName);
            % updating the data in GrthParamT
            app.GrthParamT.Data = params;

            varNames = app.system.getModelVarNames();
            app.SysVarT.Data = varNames;

            % updating the Param editors with CompParamDD callback
            app.CompParamDDValueChanged();
            % getting growth parameter data for regression
            app.RegCompParamDDValueChanged();

            % updating Editing
            app.system.setEditing(false);

            % updates MSumL text
            app.updateMSumL();
        end

        % Button pushed function: FuncNewAB
        function FuncNewABButtonPushed(app, event)
            compName = app.InteractCompDD.Value;
            funcCombo = app.FuncComboDD.Value;
            funcType = app.FuncTypeDD.Value;

            % set governing func saved status to "Not Saved"
            app.SaveL.Text = "Not Saved";
            
            % update the SubFunc DD, SubFunc name, SubFunc value, updating
            % these will cause the updating of the GovFuncL, once the user
            % saves the function, then the function is actually
            % incorporated into Species or Chemical
            app.SubFuncNF.Value = app.system.getDefaultFuncName(compName);
            app.SubFuncVF.Value = app.system.getDefaultFuncVal(compName,funcCombo,funcType);
            app.FuncTypeDD.Items = app.system.getValidFuncs(compName,funcCombo);
            app.SubFuncDD.Items = [app.SubFuncDD.Items,app.SubFuncNF.Value];
            app.SubFuncDD.Value = app.SubFuncNF.Value;
            
            % disables relevant editors
            app.SubFuncDD.Enable = false;
            app.ModelRB.Enable = false;
            app.FuncComboDD.Enable = false;
            app.FuncTypeDD.Enable = false;
            app.FuncNewAB.Enable = false;
            app.SubFuncDD.Enable = false;
            app.SubFuncNF.Enable = true;
            app.SubFuncVF.Enable = true;

            % enable ModelUB
            app.ModelUB.Enable = true;

            if strcmp(app.FuncDispB.Text,"Function Names")
                app.SubFuncNFValueChanging(app.SubFuncNF);
            elseif strcmp(app.FuncDispB.Text,"Function Values")
                app.SubFuncVFValueChanging(app.SubFuncVF);
            end

            % update SubFuncDD
            app.setCurrent();
        end

        % Button pushed function: ModelRB
        function ModelRBButtonPushed(app, event)
            if ~isempty(app.SubFuncDD.Items)
                compName = app.InteractCompDD.Value;
                app.system.removeModel();
    
                % updates Gov Func display
                app.FuncDispBValueChanged();
    
                % enables SubFuncDD
                app.SubFuncDD.Enable = true;
    
                % update SubFuncDD
                app.SubFuncDD.Value = app.SubFuncDD.Items{1};
                items = convertCharsToStrings(app.SubFuncDD.Items);
                items(items == app.SubFuncNF.Value) = [];
                app.SubFuncDD.Items = items;
                app.SubFuncDDValueChanged();

                % update SubFuncDD
                app.setCurrent();

                % getting growth parameter data from Species
                params = app.system.getGrthParamsByCompName(compName);
                % updating the data in GrthParamT
                app.GrthParamT.Data = params;
    
                % updating the Param editors with CompParamDD callback
                app.CompParamDDValueChanged();
                % getting growth parameter data for regression
                app.RegCompParamDDValueChanged();

                % updating Editing
                app.system.setEditing(false);
    
                % updates MSumL text
                app.updateMSumL();
            end
        end

        % Value changing function: SubFuncNF
        function SubFuncNFValueChanging(app, event)
            funcName = string(event.Value);
            compName = app.InteractCompDD.Value;
            funcCombo = app.FuncComboDD.Value;

            % update the name of the function in SubFuncDD
            items = convertCharsToStrings(app.SubFuncDD.Items);
            items(items == app.SubFuncDD.Value) = funcName;
            app.SubFuncDD.Items = items;
            app.SubFuncDD.Value = funcName;
            
            % set editing
            if app.SaveL.Text == "Saved"
                app.system.setEditing(true);
                % disables relevant editors
                app.SubFuncDD.Enable = false;
                app.ModelRB.Enable = false;
                app.FuncComboDD.Enable = false;
                app.FuncTypeDD.Enable = false;
                app.FuncNewAB.Enable = false;
                app.SubFuncDD.Enable = false;
                app.SubFuncVF.Enable = false;
    
                % enable ModelUB
                app.ModelUB.Enable = true;

                % change the GovFuncL to functions
                app.FuncDispB.Value = false;
                app.FuncDispBValueChanged();
            end

            % ### FIXME edited functionality
            if ~app.FuncDispB.Value
                % set governing func saved status to "Not Saved"
                app.SaveL.Text = "Not Saved";
                
                % update the text display for the function name (doesn't save
                % the function)
                compSym = app.system.getCompSymByName(compName);
                existFuncMult = app.system.getGovFuncByCompName(compName,"Multiplicative","Names");
                existFuncSum = app.system.getGovFuncByCompName(compName,"Additive","Names");

                % ### FIXME: add logic to edit existing function, not
                % create new function
                if app.system.getEditing()
                    % create functionality for getGovFuncByCompName to
                    % return two parts, GovFunc before and after the
                    % currently changing function
                    if funcCombo == "Multiplicative"
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+"\left("+latex(str2sym(funcName))+"\right)"+existFuncMult(2)+existFuncSum(1);
                    else
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1)+"+\left("+latex(str2sym(funcName))+"\right)"+existFuncSum(2);
                    end
                else
                    % update the text display for the function name (doesn't save
                    % the function)
                    if funcCombo == "Multiplicative"
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+"\left("+latex(str2sym(funcName))+"\right)"+existFuncSum(1);
                    else
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1)+"+\left("+latex(str2sym(funcName))+"\right)";
                    end
                end
    
                app.GovFuncL.Text = funcStr;
            end
        end

        % Value changing function: SubFuncVF
        function SubFuncVFValueChanging(app, event)
            funcVal = event.Value;
            
            % set editing
            if app.SaveL.Text == "Saved"
                app.system.setEditing(true);
                % disables relevant editors
                app.SubFuncDD.Enable = false;
                app.ModelRB.Enable = false;
                app.FuncComboDD.Enable = false;
                app.FuncTypeDD.Enable = false;
                app.FuncNewAB.Enable = false;
                app.SubFuncNF.Enable = false;
    
                % enable ModelUB
                app.ModelUB.Enable = true;

                % change the GovFuncL to functions
                app.FuncDispB.Value = true;
                app.FuncDispBValueChanged();
            end

            try
                newFunc = latex(str2sym(funcVal));
                app.SubFuncVF.BackgroundColor = [1,1,1];
                app.ModelUB.Enable = true;
                disp("success")
            catch
                % turns SubFuncVF color red
                app.SubFuncVF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.ModelUB.Enable = false;
                disp("err")
                return;
            end
            
            if app.FuncDispB.Value
                compName = app.InteractCompDD.Value;
                funcCombo = app.FuncComboDD.Value;
    
                % set governing func saved status to "Not Saved"
                app.SaveL.Text = "Not Saved";

                compSym = app.system.getCompSymByName(compName);
                existFuncMult = app.system.getGovFuncByCompName(compName,"Multiplicative","Values");
                existFuncSum = app.system.getGovFuncByCompName(compName,"Additive","Values");
                for k=1:1:length(existFuncSum)
                    if existFuncSum(k) ~= ""
                        existFuncSum(k) = "+"+existFuncSum(k);
                    end
                end

                if app.system.getEditing()
                    if funcCombo == "Multiplicative"
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+"\left("+newFunc+"\right)"+existFuncMult(2)+existFuncSum(1);
                    else
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1)+"+\left("+newFunc+"\right)"+existFuncSum(2);
                    end
                else
                    % update the text display for the function name (doesn't save
                    % the function)
                    if funcCombo == "Multiplicative"
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+"\left("+newFunc+"\right)"+existFuncSum(1);
                    else
                        funcStr = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1)+"+\left("+newFunc+"\right)";
                    end
                end
    
                app.GovFuncL.Text = funcStr;
            end
        end

        % Value changed function: FuncDispB
        function FuncDispBValueChanged(app, event)
            value = app.FuncDispB.Value;
            compName = app.InteractCompDD.Value;
            compSym = app.system.getCompSymByName(compName);
            if value == false
                app.FuncDispB.Text = "Function Names";
                if app.SaveL.Text == "Not Saved"
                    app.SubFuncNFValueChanging(app.SubFuncNF);
                else
                    existFuncMult = app.system.getGovFuncByCompName(compName,"Multiplicative","Names");
                    existFuncSum = app.system.getGovFuncByCompName(compName,"Additive","Names");
                    for k=1:1:length(existFuncSum)
                        if existFuncSum(k) ~= ""
                            existFuncSum(k) = "+"+existFuncSum(k);
                        end
                    end
                    app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1);
                end
            else
                app.FuncDispB.Text = "Function Values";
                if app.SaveL.Text == "Not Saved"
                    app.SubFuncVFValueChanging(app.SubFuncVF);
                else
                    existFuncMult = app.system.getGovFuncByCompName(compName,"Multiplicative","Values");
                    existFuncSum = app.system.getGovFuncByCompName(compName,"Additive","Values");
                    for k=1:1:length(existFuncSum)
                        if existFuncSum(k) ~= ""
                            existFuncSum(k) = "+"+existFuncSum(k);
                        end
                    end
                    app.GovFuncL.Text = "$\frac{d"+compSym+"}{dt}="+existFuncMult(1)+existFuncSum(1);
                end
            end
        end

        % Value changed function: FuncComboDD
        function FuncComboDDValueChanged(app, event)
            compName = app.InteractCompDD.Value;
            funcCombo = app.FuncComboDD.Value;
            app.FuncTypeDD.Items = app.system.getValidFuncs(compName,funcCombo);
            app.FuncTypeDD.Value = app.FuncTypeDD.Items{1};

            % setting SubFuncDD Items
            app.SubFuncDD.Items = app.system.getCompFuncNames(compName,funcCombo);

            % setting SubFuncNF and SubFuncVF values
            if isempty(app.SubFuncDD.Items)
                app.SubFuncNF.Value = "";
                app.SubFuncVF.Value = "";
            else
                app.SubFuncNF.Value = app.SubFuncDD.Value;
                app.SubFuncVF.Value = app.system.getCompFuncByFuncName(compName,app.SubFuncNF.Value);
            end

            % setting limit editor values
            

            % update SubFuncDD
            app.SubFuncDDValueChanged();
        end

        % Value changed function: SubFuncDD
        function SubFuncDDValueChanged(app, event)
            funcName = app.SubFuncDD.Value;
            if ~isempty(funcName)
                compName = app.InteractCompDD.Value;
                
                % update SubFuncNF and SubFuncVF
                app.SubFuncNF.Value = funcName;
                app.SubFuncVF.Value = app.system.getCompFuncByFuncName(compName,funcName);
    
                % update color coding of GovFuncL
                
            else
                app.SubFuncNF.Value = "";
                app.SubFuncVF.Value = "";
            end
            % set current
            app.setCurrent();
        
        end

        % Value changed function: SpecChemToggleB
        function SpecChemToggleBValueChanged(app, event)
            value = app.SpecChemToggleB.Value;
            if value
                app.SpecChemToggleB.Text = "Chemicals";
            else
                app.SpecChemToggleB.Text = "Species";
            end

            % updates text in MSumL
            app.updateMSumL();
        end

        % Value changed function: NbAxesDD, PlotTitleF
        function PlotTitleAxUBButtonPushed(app, event)
            plotName = app.PlotTitleDD.Value;
            title = app.PlotTitleF.Value;
            if app.NbAxesDD.Value == "2-Axis"
                axesNb = 2;
            else
                axesNb = 3;
                app.system.updatePlotNameAx(plotName,title,axesNb);
                app.AxVarUBButtonPushed(struct('Source',app.YAxVarAB));
            end
            % ### FIXME: add code to disable Z-axis if only 2 axes
            % specified
            axesUI = app.system.updatePlotNameAx(plotName,title,axesNb);
            app.updateAxesUI(axesUI);
            items = convertCharsToStrings(app.PlotTitleDD.Items);
            items(items == app.PlotTitleDD.Value) = title;
            app.PlotTitleDD.Items = items;
            app.PlotTitleDD.Value = title;

            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
%             app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: PlotAB
        function PlotABButtonPushed(app, event)
            % ### FIXME: convert to more efficient plot data getting
            [plot,axes] = app.system.createNewPlot();
            app.NbAxesDD.Value = app.NbAxesDD.Items{1};
            app.updatePlotUI(plot);
            app.PlotTitleDD.Items = [app.PlotTitleDD.Items,plot{1}];
            app.PlotTitleDD.Value = plot{1};
%             app.(dir+"AxVarL").Text = "Variable(s): "+varName;

            % setting default axes editor values
            app.updateAxesUI(axes);
        end

        % Button pushed function: PlotRB
        function PlotRBButtonPushed(app, event)
            % update title, axes number, save as function, generation
            % options, initial condition stuff
            plotName = app.PlotTitleDD.Value;
            if length(app.PlotTitleDD.Items) > 1 
                [plot,axes] = app.system.removePlot(plotName,false);
                app.updatePlotUI(plot);
                items = convertCharsToStrings(app.PlotTitleDD.Items);
                items(items == plotName) = [];
                app.PlotTitleDD.Items = items;
                app.PlotTitleDD.Value = app.PlotTitleDD.Items{1};
                for k=1:1:length(app.plotDispTreeNodes)
                    if app.plotDispTreeNodes{k}.name == plotName
                        app.plotDispTreeNodes{k}.main.delete();
                        app.plotDispTreeNodes{k}.display.delete();
                        app.plotDispTreeNodes{k}.download.delete();
                        app.plotDispTreeNodes(k) = [];
                        break;
                    end
                end
                app.updateAxesUI(axes);
            else
                % code to reset entire plot customization tab to default,
                % remove last item
                [plot,axes] = app.system.removePlot(plotName,true);
            end
        end

        % Value changed function: ICEvalAxisDD
        function ICEvalAxisDDValueChanged(app, event)
            axisName = app.ICEvalAxisDD.Value;
            plotName = app.PlotTitleF.Value;
            % 1 - Vars, 2 - Eval Var at t, 3 - Lower Eval Lim, 4 - Upper
            % Eval Lim, 5 - Number of Eval Points, 6 - isDV (boolean), 7 -
            % varIsIC (boolean)
            ICEvalData = app.system.getAxisICEvalData(plotName,axisName);
            app.ICEvalVarDD.Items = ICEvalData{1};
            app.ICEvalVarDD.Value = app.ICEvalVarDD.Items{1};
            % ### FIXME: get units for variables, get options for lo and up
            % eval lims
            if ICEvalData{6}
                % ### FIXME: add logic to activate tEvalDD if both IVs are
                % ICs
                app.AddSpecDD1.Enable = false;
                app.AddSpecDD2.Enable = false;
                app.AddSpecDD3.Enable = false;
                app.SysVarInitCondT.ColumnName = {'Variable','Units','t-Value for Evaluation'};
                app.SysVarInitCondT.Data = {app.ICEvalAxisDD.Value,'g/L',ICEvalData{2}};
            else
                if ICEvalData{7}
                    app.AddSpecDD1.Enable = true;
                    app.AddSpecDD2.Enable = true;
                    app.AddSpecDD3.Enable = true;
                    app.SysVarInitCondT.ColumnName = {'Variable','Units','Evaluation Range','# Pts'};
                    app.SysVarInitCondT.Data = {app.ICEvalAxisDD.Value,'g/L',char(ICEvalData{3}+"-"+ICEvalData{4}),ICEvalData{5}};
                else
                    % code to make ICEval panel inactive
                    app.AddSpecDD1.Enable = false;
                    app.AddSpecDD2.Enable = false;
                    app.AddSpecDD3.Enable = false;
                    app.SysVarInitCondT.ColumnName = {'Variable','Units'};
                    app.SysVarInitCondT.Data = {app.ICEvalAxisDD.Value,'g/L'};
                end
            end
            app.ICEvalVarDDValueChanged();
        end

        % Button pushed function: XAxDispLimUB, YAxDispLimUB, ZAxDispLimUB
        function AxDispLimUBButtonPushed(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            loDispLim = app.(dir+"AxLoDispLimDD").Value;
            upDispLim = app.(dir+"AxUpDispLimDD").Value;
            useDefR = app.(dir+"AxDefaultRCB").Value;
            app.system.updateAxDispLims(plotName,dir,loDispLim,upDispLim,useDefR);
        end

        % Button pushed function: SelectDirectoryB
        function SelectDirectoryBButtonPushed(app, event)
            dirName = uigetdir;
            plotName = app.PlotTitleDD.Value;
            app.system.setPlotDir(plotName,dirName);
            app.PlotDirL.Text = "Directory: "+dirName;
        end

        % Button pushed function: GenPlotB
        function GenPlotBButtonPushed(app, event)
            if app.ClosePrevPlotsCB.Value
                close all;
            end

            app.system.compileModel();
        end

        % Callback function: PlotDispTree
        function PlotDispTreeCheckedNodesChanged(app, event)
            % ### FIXME: issue with Parent node being switched if the
            % actual parent is not checked, this causes inaccuracies.
            checkedNodes = app.PlotDispTree.CheckedNodes;
            for k=1:1:length(checkedNodes)
                if checkedNodes(k).Text == "Display"
                    prop = "display";
                    plotName = checkedNodes(k).Parent.Text;
                    app.system.updatePlotDispDownload(plotName,prop,true);
                elseif checkedNodes(k).Text == "Download"
                    prop = "download";
                    plotName = checkedNodes(k).Parent.Text;
                    app.system.updatePlotDispDownload(plotName,prop,true);
                else
                    plotName = checkedNodes(k).Text;
                    app.system.updatePlotDispDownload(plotName,"display",true);
                    app.system.updatePlotDispDownload(plotName,"download",true);
                end
            end
        end

        % Button pushed function: XAxVarAB, XAxVarRB, YAxVarAB, YAxVarRB, 
        % ZAxVarAB, ZAxVarRB
        function AxVarUBButtonPushed(app, event)
            % ### FIXME: add functionality to update whether selected
            % variable is a set of IC
            % add functionality to update the options for the ranges of max
            % and min
            tag = split(event.Source.Tag,"_");
            dir = tag(1);
            if tag(2) == "add"
                addVar = true;
            else
                addVar = false;
            end
            plotName = app.PlotTitleF.Value;
            varName = string(app.(dir+"AxVarDD").Value);
            app.(dir+"AxVarL").Text = app.system.updateAxVars(plotName,dir,varName,addVar);
            app.ICEvalAxisDDValueChanged();
        end

        % Value changed function: XAxTitleF, YAxTitleF, ZAxTitleF
        function AxTitleUpdate(app, event)
            dir = event.Source.Tag;
            plotName = app.PlotTitleF.Value;
            title = app.(dir+"AxTitleF").Value;
            axesUI = app.system.updateAxTitle(plotName,dir,title);
            % update IC + Eval Specs
            axesNames = cell(length(axesUI),1);
            for k=1:1:length(axesNames)
                axesNames{k} = axesUI{k}{2};
            end
            app.ICEvalAxisDD.Items = string(axesNames);
            app.ICEvalAxisDD.Value = app.ICEvalAxisDD.Items{1};
            app.ICEvalAxisDDValueChanged();
        end

        % Button pushed function: PlotICEvalSpecUB
        function PlotICEvalSpecUBButtonPushed(app, event)
            plotName = app.PlotTitleF.Value;
            axisName = app.ICEvalAxisDD.Value;
            evaltVal = app.AddSpecDD1.Value;
            loEvalLim = app.AddSpecDD1.Value;
            upEvalLim = app.AddSpecDD2.Value;
            nbEvalPts = app.AddSpecDD3.Value;
            % need to be able to differentiate between ICVar and normal var
            if length(app.ICEvalVarDD.Items) > 1
                varName = string(app.ICEvalVarDD.Value);
                app.system.setVarICEvalData(plotName,axisName,varName,evaltVal,loEvalLim,upEvalLim,nbEvalPts);
            else
                app.system.setAxisICEvalData(plotName,axisName,evaltVal,loEvalLim,upEvalLim,nbEvalPts);
            end
        end

        % Value changed function: ICEvalVarDD
        function ICEvalVarDDValueChanged(app, event)
            varName = string(app.ICEvalVarDD.Value);
            plotName = app.PlotTitleF.Value;
            axisName = app.ICEvalAxisDD.Value;
            % ### FIXME: put in options for each of the DD
            % ### FIXME: also need to make sure that only the relevant
            % variables are being displayed
            % 1 - evaltVal, 2 - loEvalLim, 3 - upEvalLim, 4 - nbEvalPts, 5
            % - isDV, 6 - varIsIC, 7 - needDefineEvalt
            varData = app.system.getVarICEvalData(plotName,axisName,varName);
            UIcomps = ["evalUnitsDD","AddSpecDD1","AddSpecDD2","AddSpecDD3"];
            for k=1:1:length(UIcomps)
                if ~any(strcmp(app.(UIcomps(k)).Items,string(varData(k))))
                    app.(UIcomps(k)).Items = [app.(UIcomps(k)).Items,string(varData(k))];
                end
                app.(UIcomps(k)).Value = string(varData(k));
            end
            
            % if var is DV
            if varData{5}
                % if need to define eval t
                if varData{7}
                    app.AddSpecL1.Text = "Eval @ t =";
                    app.AddSpecL1.Visible = true;
                    app.AddSpecDD1.Visible = true;
                    app.AddSpecDD1.Enable = true;
                    % ### FIXME: add time evaluation options, but wait
                    % until the mass transfer portion has been finished


                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecDD2.Visible = false;
                    app.AddSpecDD2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecDD3.Visible = false;
                    app.AddSpecDD3.Enable = false;
                else
                    app.AddSpecL1.Text = "";
                    app.AddSpecL1.Visible = false;
                    app.AddSpecDD1.Visible = false;
                    app.AddSpecDD1.Enable = false;

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecDD2.Visible = false;
                    app.AddSpecDD2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecDD3.Visible = false;
                    app.AddSpecDD3.Enable = false;
                end
            else
                % if var is IC
                if varData{6}
                    app.AddSpecL1.Text = "Lower Evaluation Limit =";
                    app.AddSpecL1.Visible = true;
                    app.AddSpecDD1.Visible = true;
                    app.AddSpecDD1.Enable = true;
                    % ### FIXME: also add the options for the DD

                    app.AddSpecL2.Text = "Upper Evaluation Limit =";
                    app.AddSpecL2.Visible = true;
                    app.AddSpecDD2.Visible = true;
                    app.AddSpecDD2.Enable = true;

                    app.AddSpecL3.Text = "Number of Evaluation Points (#Pts) =";
                    app.AddSpecL3.Visible = true;
                    app.AddSpecDD3.Visible = true;
                    app.AddSpecDD3.Enable = true;
                else
                    app.AddSpecL1.Text = "";
                    app.AddSpecL1.Visible = false;
                    app.AddSpecDD1.Visible = false;
                    app.AddSpecDD1.Enable = false;

                    app.AddSpecL2.Text = "";
                    app.AddSpecL2.Visible = false;
                    app.AddSpecDD2.Visible = false;
                    app.AddSpecDD2.Enable = false;

                    app.AddSpecL3.Text = "";
                    app.AddSpecL3.Visible = false;
                    app.AddSpecDD3.Visible = false;
                    app.AddSpecDD3.Enable = false;
                end
            end
        end

        % Value changed function: PlotTitleDD
        function PlotTitleDDValueChanged(app, event)
            plotName = app.PlotTitleDD.Value;
            plot = app.system.getPlotByName(plotName);
            plotUI = plot.getAllPlotProps();
            axesUI = plot.getAllAxProps();
            app.updatePlotUI(plotUI);
%             app.PlotTitleF.Value = plotName;
%             app.NbAxesDD.Value = app.NbAxesDD.Items{length(axesUI)-1};
            app.updateAxesUI(axesUI);
        end

        % Button pushed function: ImportDataB
        function ImportBButtonPushed(app, event)
            [importedData,path,colNames] = app.system.getUserImportedData();
            app.ImportDataB.Enable = false;
            app.DataImportWindow = DataImportWindow(app,path,importedData,colNames,app.ImportDataB); %#ok<ADPROPLC> 
        end

        % Value changed function: RegCompParamDD
        function RegCompParamDDValueChanged(app, event)
            % ### FIXME: add functionality to take both helper functions
            % and env functions
            compName = app.RegCompParamDD.Value;
            % getting growth parameter data from Species
            params = app.system.getGrthParamsByCompName(compName);
            app.RegParamDD.Items = params(:,3);
        end

        % Button pushed function: RegParamAB, RegParamRB
        function RegParamUpdate(app, event)
            tag = event.Source.Tag;
            paramSym = app.RegParamDD.Value;
            compName = app.RegCompParamDD.Value;
            params = app.system.updateRegParamList(compName,paramSym,tag);
            app.RegParamT.Data = params;
            app.SaveRegParamDD.Items = params(:,1);
        end

        % Button pushed function: VarDataMatchB, VarDataUnmatchB
        function UpdateDVDataMatching(app, event)
            match = event.Source.Tag;
            sysVar = app.RegSysVarDD.Value;
            importVar = app.ImportedDataDD.Value;
            importVarNum = convertCharsToStrings(app.ImportedDataDD.Items) == convertCharsToStrings(importVar);
            matches = app.system.updateVarMatch(sysVar,importVar,importVarNum,match);
            app.RegVarDataT.Data = matches;
            matches_2 = matches(:,1);
            app.RegDVDD.Items = matches_2(~strcmp(matches_2,'t'));
            regParamList = app.system.getRegParamList();
            if ~isempty(app.RegDVDD.Items) && ~isempty(regParamList)
                app.RegSpecsB.Enable = true;
                app.RunRegB.Enable = true;
            else
                app.RegSpecsB.Enable = false;
                app.RunRegB.Enable = false;
                app.RegAnalysisB.Enable = false;
            end
        end

        % Button pushed function: RegDVAB
        function RegDVPlotUpdate(app, event)
            varName = string(app.RegDVDD.Value);
            comps = app.system.getModelVarNames(true);
            DVIdx = strcmp(comps(:,2),varName);
            [plotRegData,plotImportData] = app.system.updateRegPlotDV(DVIdx);
            cla(app.RegPlot);
            hold(app.RegPlot,"on");
            plot(app.RegPlot,plotImportData(:,1),plotImportData(:,2),'ko');
            plot(app.RegPlot,linspace(plotRegData(1,1),plotRegData(end,1),100), ...
                spline(plotRegData(:,1),plotRegData(:,2), ...
                linspace(plotRegData(1,1),plotRegData(end,1),100)),'k-');
            hold(app.RegPlot,"off");
        end

        % Button pushed function: RegSpecsB
        function RegSpecsBButtonPushed(app, event)
            regSpecs = app.system.getRegSpecs();
            regParamList = app.system.getRegParamList();
            regParamList = regParamList(:,[1,4]);
            app.RegSpecsB.Enable = false;
            app.RegSpecsWindow = RegSpecsWindow(app,regSpecs,regParamList,app.RegSpecsB); %#ok<ADPROPLC> 
        end

        % Button pushed function: RunRegB
        function RunRegBPushed(app, event)
            % ### FIXME: for the starting point for the regression, the
            % first data point of the imported data should be the initial
            % condition for the regression, but since not every DV will
            % necessarily be imported, need to just recommend that each
            % imported data set start at time = 0
            regStats = app.system.compileRegression();
            varName = string(app.RegDVDD.Value);
            comps = app.system.getModelVarNames(true);
            DVIdx = strcmp(comps(:,2),varName);
            cla(app.RegPlot);
            hold(app.RegPlot,"on");
            plot(app.RegPlot,regStats.IVs,regStats.DVs(:,(regStats.importedDataIdx == find(DVIdx))),'ko');
            plot(app.RegPlot,linspace(regStats.tRes(1),regStats.tRes(end),100), ...
                spline(regStats.tRes,regStats.yRes(:,DVIdx), ...
                linspace(regStats.tRes(1),regStats.tRes(end),100)),'k-');
            hold(app.RegPlot,"off");

            for k=1:1:length(regStats.beta), app.RegParamT.Data{k,5} = regStats.beta(k); end

            app.RegAnalysisB.Enable = true;
        end

        % Close request function: MAGMAUIFigure
        function MAGMAUIFigureCloseRequest(app, event)
            delete(app.RegSpecsWindow)
            delete(app.DataImportWindow)
            delete(app.RegAnalysisWindow)
            delete(app)
            
        end

        % Button pushed function: RegAnalysisB
        function RegAnalysisBPushed(app, event)
            % ### FIXME: need to code the nlparci and nlpredci functions,
            % give user options to customize the analysis
            % create popup window that can give these options to user
            % NRMSE should be done per DV
            app.RegAnalysisWindow = RegAnalysisWindow(app); %#ok<ADPROPLC>
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            disp(jsonencode(app.system));
        end

        % Button pushed function: HelperAB
        function HelperABButtonPushed(app, event)
            helperName = "Helper Function "+(length(app.HelperDD.Items)+1);
            app.HelperNameF.Value = helperName;
            app.HelperSymF.Value = "H_F_"+(length(app.HelperDD.Items)+1);
            app.HelperValF.Value = "1";
            app.HelperDD.Items = [app.HelperDD.Items,helperName];
            app.HelperDD.Value = app.HelperDD.Items{end};

            app.HelperAB.Enable = false;
            app.HelperRB.Enable = false;
            app.HelperDD.Enable = false;
            app.HelperUB.Enable = true;

            app.HelperSaveL.Text = "Not Saved";
        end

        % Button pushed function: HelperUB
        function HelperUBButtonPushed(app, event)
            funcName = app.HelperNameF.Value;
            funcSym = app.HelperSymF.Value;
            funcVal = app.HelperValF.Value;

            nameEnabled = app.HelperNameF.Enable;
            symEnabled = app.HelperSymF.Enable;
            valEnabled = app.HelperValF.Enable;

            if nameEnabled && symEnabled && valEnabled
                app.system.addRmHelperFuncs(funcName,funcSym,funcVal,true);
            elseif nameEnabled && ~symEnabled && ~valEnabled
                app.system.updateHelperFuncs("name",funcSym,funcVal);
            elseif ~nameEnabled && symEnabled && ~valEnabled
                app.system.updateHelperFuncs("sym",funcName,funcVal);
            elseif ~nameEnabled && ~symEnabled && valEnabled
                app.system.updateHelperFuncs("val",funcName,funcSym);
            end

            app.HelperAB.Enable = true;
            app.HelperRB.Enable = true;
            app.HelperDD.Enable = true;
            app.HelperUB.Enable = false;
            app.HelperNameF.Enable = true;
            app.HelperSymF.Enable = true;
            app.HelperValF.Enable = true;

            app.HelperSaveL.Text = "Saved";

            % add helperFunc to sysVar table
            varNames = app.system.getModelVarNames();
            app.SysVarT.Data = varNames;
        end

        % Button pushed function: HelperRB
        function HelperRBButtonPushed(app, event)
            funcName = app.HelperNameF.Value;
            funcSym = app.HelperSymF.Value;
            funcVal = app.HelperValF.Value;
            app.system.addRmHelperFuncs(funcName,funcSym,funcVal,false);

            app.HelperDDValueChanged();
        end

        % Value changed function: HelperDD
        function HelperDDValueChanged(app, event)
            funcName = app.HelperDD.Value;
            app.HelperNameF.Value = funcName;
            [funcSym,funcVal] = app.system.getHelperFunc(funcName);
            app.HelperSymF.Value = funcSym;
            app.HelperValF.Value = funcVal;

            app.HelperValFValueChanging();
        end

        % Value changing function: HelperValF
        function HelperValFValueChanging(app, event)
            funcVal = string(event.Value);
            funcSym = string(app.HelperSymF.Value);

            % set editing
            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperNameF.Enable = false;
                app.HelperSymF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
            end

            try
                funcVal = latex(str2sym(funcVal));
                app.HelperValF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                disp("success")
            catch
                % turns HelperSymF color red
                app.HelperValF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.HelperUB.Enable = false;
                disp("err")
                return;
            end

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";

            funcStr = "$" + funcSym + "=" + funcVal + "$";
            
            app.HelperFuncL.Text = funcStr;
        end

        % Value changing function: HelperNameF
        function HelperNameFValueChanging(app, event)
            funcName = string(event.Value);
            
            % set editing
            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperSymF.Enable = false;
                app.HelperValF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
            end

            try
                funcName = latex(str2sym(funcName));
                app.HelperNameF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                disp("success")
            catch
                % turns SubFuncVF color red
                app.HelperNameF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.HelperUB.Enable = false;
                disp("err")
                return;
            end

            % update the name of the function in SubFuncDD
            items = convertCharsToStrings(app.HelperDD.Items);
            items(items == app.HelperDD.Value) = funcName;
            app.HelperDD.Items = items;
            app.HelperDD.Value = funcName;

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";
        end

        % Value changing function: HelperSymF
        function HelperSymFValueChanging(app, event)
            funcSym = string(event.Value);
            funcVal = string(app.HelperValF.Value);

            % set editing
            if app.HelperSaveL.Text == "Saved"
                % disables relevant editors
                app.HelperAB.Enable = false;
                app.HelperDD.Enable = false;
                app.HelperRB.Enable = false;
                app.HelperAB.Enable = false;
                app.HelperNameF.Enable = false;
                app.HelperValF.Enable = false;
    
                % enable ModelUB
                app.HelperUB.Enable = true;
            end

            try
                funcSym = latex(str2sym(funcSym));
                app.HelperSymF.BackgroundColor = [1,1,1];
                app.HelperUB.Enable = true;
                disp("success")
            catch
                % turns HelperSymF color red
                app.HelperSymF.BackgroundColor = [1,0.7,0.7];
                % makes ModelUB inactive
                app.HelperUB.Enable = false;
                disp("err")
                return;
            end

            % set helper func saved status to "Not Saved"
            app.HelperSaveL.Text = "Not Saved";

            funcStr = "$" + funcSym + "=" + latex(str2sym(funcVal)) + "$";
            
            app.HelperFuncL.Text = funcStr;
        end

        % Button pushed function: SaveRegParamB
        function SaveRegParamBButtonPushed(app, event)
            paramSym = app.SaveRegParamDD.Value;
            RegParamT_Idx = strcmp(app.RegParamT.Data(:,1),paramSym);
            compName = app.RegParamT.Data{RegParamT_Idx,2};
            newParamVal = app.RegParamT.Data{:,5};

            compNames = [app.system.getCompNamesByType("spec");app.system.getCompNamesByType("chem")];
            envFuncNames = app.system.getEnvFuncNames();
            helperFuncNames = app.system.getHelperFuncNames();
            if any(strcmp(compNames,compName))
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            param_idx = strcmp(params(:,3),paramSym);
            newParamUnit = params{param_idx,6};
            newParamName = params{param_idx,4};
            funcName = params{param_idx,2};

            if any(strcmp(compNames,compName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByCompName(compName);
            elseif any(strcmp(envFuncNames(:,1),compName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByEnvFuncName(compName);
            elseif any(strcmp(helperFuncNames(:,1),compName))
                app.system.updateMultiParams(paramSym,newParamVal,newParamUnit,newParamName,funcName);
                params = app.system.getGrthParamsByHelperFuncName(compName);
            else
                return;
            end

            % updating the data in RegParamT
            app.RegParamT.Data{RegParamT_Idx,4} = app.RegParamT.Data{RegParamT_Idx,5};

            % updating the data in GrthParamT
            app.GrthParamT.Data = params;
        end

        % Button pushed function: SaveAllRegParamB
        function SaveAllRegParamBButtonPushed(app, event)
            paramSym = string(app.SaveRegParamDD.Items);
            newParamVal = [app.RegParamT.Data{:,5}];
            for k=1:1:length(paramSym)
                compName = app.RegParamT.Data{k,2};
    
                compNames = [app.system.getCompNamesByType("spec");app.system.getCompNamesByType("chem")];
                envFuncNames = app.system.getEnvFuncNames();
                helperFuncNames = app.system.getHelperFuncNames();
                if any(strcmp(compNames,compName))
                    params = app.system.getGrthParamsByCompName(compName);
                elseif any(strcmp(envFuncNames(:,1),compName))
                    params = app.system.getGrthParamsByEnvFuncName(compName);
                elseif any(strcmp(helperFuncNames(:,1),compName))
                    params = app.system.getGrthParamsByHelperFuncName(compName);
                else
                    return;
                end

                param_idx = strcmp(convertCharsToStrings(params(:,3)),paramSym(k));
                newParamUnit = params{param_idx,6};
                newParamName = params{param_idx,4};
                funcName = params{param_idx,2};
    
                if any(strcmp(compNames,compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,funcName);
                    params = app.system.getGrthParamsByCompName(compName);
                elseif any(strcmp(envFuncNames(:,1),compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,funcName);
                    params = app.system.getGrthParamsByEnvFuncName(compName);
                elseif any(strcmp(helperFuncNames(:,1),compName))
                    app.system.updateMultiParams(paramSym(k),newParamVal(k),newParamUnit,newParamName,funcName);
                    params = app.system.getGrthParamsByHelperFuncName(compName);
                else
                    return;
                end
    
                % updating the data in RegParamT
                app.RegParamT.Data{k,4} = app.RegParamT.Data{k,5};
            end

            % updating the data in GrthParamT
            app.GrthParamT.Data = params;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MAGMAUIFigure = uifigure('Visible', 'off');
            app.MAGMAUIFigure.Name = 'MAGMA';
            app.MAGMAUIFigure.CloseRequestFcn = createCallbackFcn(app, @MAGMAUIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.MAGMAUIFigure);
            app.HomeTab = uitab(app.TabGroup);
            app.HomeTab.Title = 'Home';
            app.GridLayout14 = uigridlayout(app.HomeTab);
            app.SpeciesLabel = uilabel(app.GridLayout14);
            app.SpeciesLabel.Text = 'Species:';
            app.SubstratesLabel = uilabel(app.GridLayout14);
            app.SubstratesLabel.Text = 'Substrates:';
            app.SearchButton = uibutton(app.GridLayout14, 'push');
            app.SearchButton.Text = 'Search';
            app.AddButton = uibutton(app.GridLayout14, 'push');
            app.AddButton.Text = 'Add';
            app.CustomSchemeEditFieldLabel = uilabel(app.GridLayout14);
            app.CustomSchemeEditFieldLabel.Text = 'Custom Scheme';
            app.CustomSchemeEditField = uieditfield(app.GridLayout14, 'text');
            app.ReferenceLabel = uilabel(app.GridLayout14);
            app.ReferenceLabel.Text = 'Reference:';
            app.EnvironmentLabel = uilabel(app.GridLayout14);
            app.EnvironmentLabel.Text = 'Environment:';
            app.ModelSubjectLabel = uilabel(app.GridLayout14);
            app.ModelSubjectLabel.Text = 'Model Subject:';
            app.ListBox = uilistbox(app.GridLayout14);
            app.ListBox.Items = {};
            app.ListBox.Value = {};
            app.SetButton = uibutton(app.GridLayout14, 'push');
            app.SetButton.Text = 'Set';
            app.ModelSchemeEditFieldLabel = uilabel(app.GridLayout14);
            app.ModelSchemeEditFieldLabel.Text = 'Model Scheme';
            app.ModelSchemeEditField = uieditfield(app.GridLayout14, 'text');
            app.EditButton = uibutton(app.GridLayout14, 'push');
            app.EditButton.Text = 'Edit';
            app.DocumentationButton = uibutton(app.GridLayout14, 'push');
            app.DocumentationButton.Text = 'Documentation';
            app.Button = uibutton(app.GridLayout14, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EnvironmentTab = uitab(app.TabGroup);
            app.EnvironmentTab.Title = 'Environment';
            app.GridLayout12 = uigridlayout(app.EnvironmentTab);
            app.EnvironParamFuncPlot = uiaxes(app.GridLayout12);
            zlabel(app.EnvironParamFuncPlot, 'Z')
            app.EnvironT = uitable(app.GridLayout12);
            app.EnvironT.ColumnName = {'Parameter'; 'Value'; 'Unit'};
            app.EnvironT.RowName = {};
            app.EnvironCustNameF = uieditfield(app.GridLayout12, 'text');
            app.EnvironCustNameF.Enable = 'off';
            app.EnvironAB = uibutton(app.GridLayout12, 'push');
            app.EnvironAB.ButtonPushedFcn = createCallbackFcn(app, @EnvironABButtonPushed, true);
            app.EnvironAB.Text = 'Add';
            app.EnvironRB = uibutton(app.GridLayout12, 'push');
            app.EnvironRB.ButtonPushedFcn = createCallbackFcn(app, @EnvironRBButtonPushed, true);
            app.EnvironRB.Text = 'Remove';
            app.EnvironCustL = uilabel(app.GridLayout12);
            app.EnvironCustL.Enable = 'off';
            app.EnvironCustL.Text = 'Custom Environment';
            app.GenParamF = uieditfield(app.GridLayout12, 'text');
            app.GenParamUnitDD = uidropdown(app.GridLayout12);
            app.GenParamUnitDD.Items = {'s', 'min', 'hr', 'day'};
            app.GenParamUnitDD.Editable = 'on';
            app.GenParamUnitDD.Value = 'hr';
            app.GenParamAB = uibutton(app.GridLayout12, 'push');
            app.GenParamAB.ButtonPushedFcn = createCallbackFcn(app, @GenParamABButtonPushed, true);
            app.GenParamAB.Text = 'Update';
            app.SetasDefaultButton = uibutton(app.GridLayout12, 'push');
            app.SetasDefaultButton.Text = 'Set as Default';
            app.ParameterDropDownLabel = uilabel(app.GridLayout12);
            app.ParameterDropDownLabel.Text = 'Parameter';
            app.GenParamDD = uidropdown(app.GridLayout12);
            app.GenParamDD.Items = {'Model Runtime (t)', 'Incident Light (I)', 'Temperature (T)', 'Initial Culture Volume (V)', 'Culture Surface Area (SA)'};
            app.GenParamDD.Editable = 'on';
            app.GenParamDD.ValueChangedFcn = createCallbackFcn(app, @GenParamDDValueChanged, true);
            app.GenParamDD.Value = 'Model Runtime (t)';
            app.ModelEnvironmentLabel = uilabel(app.GridLayout12);
            app.ModelEnvironmentLabel.Text = 'Model Environment';
            app.EnvironDD = uidropdown(app.GridLayout12);
            app.EnvironDD.Items = {'Photo-Bioreactor (PBR)', 'Eutrophic Lake', 'Test Tube', 'Shaking Flask', 'Outdoor Tank', 'Custom Environment'};
            app.EnvironDD.Editable = 'on';
            app.EnvironDD.ValueChangedFcn = createCallbackFcn(app, @EnvironDDValueChanged, true);
            app.EnvironDD.Value = 'Photo-Bioreactor (PBR)';
            app.EnvironParamFuncL = uilabel(app.GridLayout12);
            app.EnvironParamFuncL.Interpreter = 'latex';
            app.EnvironParamFuncL.Text = '$t =$';
            app.PiecewiseLimsP_2 = uipanel(app.GridLayout12);
            app.PiecewiseLimsP_2.Title = 'Set Function Limits';
            app.GridLayout20_2 = uigridlayout(app.PiecewiseLimsP_2);
            app.LimitsLabel_2 = uilabel(app.GridLayout20_2);
            app.LimitsLabel_2.Enable = 'off';
            app.LimitsLabel_2.Text = 'Limits';
            app.EditField_6Label = uilabel(app.GridLayout20_2);
            app.EditField_6Label.Enable = 'off';
            app.EditField_6Label.Text = '=';
            app.EditField_6 = uieditfield(app.GridLayout20_2, 'text');
            app.EditField_6.Enable = 'off';
            app.Label_8 = uilabel(app.GridLayout20_2);
            app.Label_8.Enable = 'off';
            app.Label_8.Text = '<';
            app.Label_9 = uilabel(app.GridLayout20_2);
            app.Label_9.Enable = 'off';
            app.Label_9.Text = '<';
            app.EditField_7 = uieditfield(app.GridLayout20_2, 'text');
            app.EditField_7.Enable = 'off';
            app.UITable3_2 = uitable(app.GridLayout20_2);
            app.UITable3_2.ColumnName = {'Lower Bound'; 'Function'; 'Upper Bound'};
            app.UITable3_2.RowName = {};
            app.UITable3_2.Enable = 'off';
            app.AddLimitButton = uibutton(app.GridLayout20_2, 'push');
            app.AddLimitButton.Enable = 'off';
            app.AddLimitButton.Text = 'Add Limit';
            app.RemoveLimitButton = uibutton(app.GridLayout20_2, 'push');
            app.RemoveLimitButton.Enable = 'off';
            app.RemoveLimitButton.Text = 'Remove Limit';
            app.DropDown_2 = uidropdown(app.GridLayout20_2);
            app.DropDown_2.Items = {};
            app.DropDown_2.Editable = 'on';
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = {};
            app.SpecEnvironTab = uitab(app.TabGroup);
            app.SpecEnvironTab.Title = 'Species and Chemicals';
            app.SpecEnvironGrid = uigridlayout(app.SpecEnvironTab);
            app.SpeciesDropDown_2Label = uilabel(app.SpecEnvironGrid);
            app.SpeciesDropDown_2Label.Text = 'Species';
            app.SpecNameDD = uidropdown(app.SpecEnvironGrid);
            app.SpecNameDD.Items = {'Synechoccocus elongatus UTEX 2973', 'Microcystis aeruginosa', 'Yarrowia lipolytico', 'Custom Species'};
            app.SpecNameDD.ValueChangedFcn = createCallbackFcn(app, @SpecNameDDValueChanged, true);
            app.SpecNameDD.Value = 'Synechoccocus elongatus UTEX 2973';
            app.SpecAB = uibutton(app.SpecEnvironGrid, 'push');
            app.SpecAB.ButtonPushedFcn = createCallbackFcn(app, @SpecABPushed, true);
            app.SpecAB.Text = 'Add/Update';
            app.SpecT = uitable(app.SpecEnvironGrid);
            app.SpecT.ColumnName = {'Number'; 'Name'; 'Initial Concentration'; 'Unit'};
            app.SpecT.RowName = {};
            app.SpecT.ColumnSortable = [true true false false];
            app.SpecT.ColumnEditable = [false true false false];
            app.SpecRB = uibutton(app.SpecEnvironGrid, 'push');
            app.SpecRB.ButtonPushedFcn = createCallbackFcn(app, @SpecRBButtonPushed, true);
            app.SpecRB.Text = 'Remove';
            app.SpecCustNameF = uieditfield(app.SpecEnvironGrid, 'text');
            app.SpecCustNameF.Enable = 'off';
            app.SpecCustL = uilabel(app.SpecEnvironGrid);
            app.SpecCustL.Enable = 'off';
            app.SpecCustL.Text = 'Custom Species';
            app.InitialConcentrationEditField_2Label = uilabel(app.SpecEnvironGrid);
            app.InitialConcentrationEditField_2Label.Text = 'Initial Concentration';
            app.SpecInitConcF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.SpecInitConcUnitDD = uidropdown(app.SpecEnvironGrid);
            app.SpecInitConcUnitDD.Items = {'g/L', 'mg/L', 'ug/L'};
            app.SpecInitConcUnitDD.Value = 'g/L';
            app.ChemAB = uibutton(app.SpecEnvironGrid, 'push');
            app.ChemAB.ButtonPushedFcn = createCallbackFcn(app, @ChemABPushed, true);
            app.ChemAB.Text = 'Add/Update';
            app.ChemCustNameF = uieditfield(app.SpecEnvironGrid, 'text');
            app.ChemCustNameF.Enable = 'off';
            app.ChemCustL = uilabel(app.SpecEnvironGrid);
            app.ChemCustL.Enable = 'off';
            app.ChemCustL.Text = 'Custom Chemical';
            app.ChemicalSpceciesNameDropDownLabel = uilabel(app.SpecEnvironGrid);
            app.ChemicalSpceciesNameDropDownLabel.Text = 'Chemical Spcecies Name';
            app.ChemNameDD = uidropdown(app.SpecEnvironGrid);
            app.ChemNameDD.Items = {'NO3^-', 'NH4^+', 'PO4^-2', 'CO2', 'O2', 'Microcystin', 'Glucose', 'Sucrose', 'SOx', 'PM2.5', 'PM10', 'Custom Chemical'};
            app.ChemNameDD.ValueChangedFcn = createCallbackFcn(app, @ChemNameDDValueChanged, true);
            app.ChemNameDD.Value = 'NO3^-';
            app.InitialConcentrationEditFieldLabel = uilabel(app.SpecEnvironGrid);
            app.InitialConcentrationEditFieldLabel.Text = 'Initial Concentration';
            app.ChemInitConcF = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.ChemInitConcUnitDD = uidropdown(app.SpecEnvironGrid);
            app.ChemInitConcUnitDD.Items = {'g/L', 'mg/L', 'ug/L'};
            app.ChemInitConcUnitDD.Value = 'g/L';
            app.ChemT = uitable(app.SpecEnvironGrid);
            app.ChemT.ColumnName = {'Number'; 'Chemical Name'; 'Initial Concentration'; 'Unit'};
            app.ChemT.RowName = {};
            app.ChemRB = uibutton(app.SpecEnvironGrid, 'push');
            app.ChemRB.ButtonPushedFcn = createCallbackFcn(app, @ChemRBButtonPushed, true);
            app.ChemRB.Text = 'Remove';
            app.MolecularWeightEditFieldLabel = uilabel(app.SpecEnvironGrid);
            app.MolecularWeightEditFieldLabel.Text = 'Molecular Weight';
            app.MolecularWeightEditField = uieditfield(app.SpecEnvironGrid, 'numeric');
            app.MassTransferTab = uitab(app.TabGroup);
            app.MassTransferTab.Title = 'Mass Transfer';
            app.GridLayout27 = uigridlayout(app.MassTransferTab);
            app.SchematicPanel = uipanel(app.GridLayout27);
            app.SchematicPanel.Title = 'Schematic';
            app.GridLayout28 = uigridlayout(app.SchematicPanel);
            app.Image = uiimage(app.GridLayout28);
            app.InputOutputFlowsPanel = uipanel(app.GridLayout27);
            app.InputOutputFlowsPanel.Title = 'Input/Output Flows';
            app.GridLayout33 = uigridlayout(app.InputOutputFlowsPanel);
            app.LiquidInCheckBox = uicheckbox(app.GridLayout33);
            app.LiquidInCheckBox.Text = 'Liquid In';
            app.LiquidOutCheckBox = uicheckbox(app.GridLayout33);
            app.LiquidOutCheckBox.Text = 'Liquid Out';
            app.GasInCheckBox = uicheckbox(app.GridLayout33);
            app.GasInCheckBox.Text = 'Gas In';
            app.GasOutCheckBox = uicheckbox(app.GridLayout33);
            app.GasOutCheckBox.Text = 'Gas Out';
            app.FlowRateEditFieldLabel = uilabel(app.GridLayout33);
            app.FlowRateEditFieldLabel.Enable = 'off';
            app.FlowRateEditFieldLabel.Text = 'Flow Rate';
            app.FlowRateEditField = uieditfield(app.GridLayout33, 'text');
            app.FlowRateEditField.Enable = 'off';
            app.DropDown_3 = uidropdown(app.GridLayout33);
            app.DropDown_3.Enable = 'off';
            app.DropDown_4 = uidropdown(app.GridLayout33);
            app.DropDown_4.Editable = 'on';
            app.DropDown_4.Enable = 'off';
            app.DropDown2 = uidropdown(app.GridLayout33);
            app.DropDown2.Editable = 'on';
            app.DropDown2.Enable = 'off';
            app.DropDown3 = uidropdown(app.GridLayout33);
            app.DropDown3.Enable = 'off';
            app.FlowRateEditField_2Label = uilabel(app.GridLayout33);
            app.FlowRateEditField_2Label.Enable = 'off';
            app.FlowRateEditField_2Label.Text = 'Flow Rate';
            app.FlowRateEditField_2 = uieditfield(app.GridLayout33, 'text');
            app.FlowRateEditField_2.Enable = 'off';
            app.FlowRateEditField_3Label = uilabel(app.GridLayout33);
            app.FlowRateEditField_3Label.Enable = 'off';
            app.FlowRateEditField_3Label.Text = 'Flow Rate';
            app.FlowRateEditField_3 = uieditfield(app.GridLayout33, 'text');
            app.FlowRateEditField_3.Enable = 'off';
            app.FlowRateEditField_4Label = uilabel(app.GridLayout33);
            app.FlowRateEditField_4Label.Enable = 'off';
            app.FlowRateEditField_4Label.Text = 'Flow Rate';
            app.FlowRateEditField_4 = uieditfield(app.GridLayout33, 'text');
            app.FlowRateEditField_4.Enable = 'off';
            app.InputFlowCompositionsandSpecificationsPanel = uipanel(app.GridLayout27);
            app.InputFlowCompositionsandSpecificationsPanel.Title = 'Input Flow Compositions and Specifications';
            app.GridLayout34 = uigridlayout(app.InputFlowCompositionsandSpecificationsPanel);
            app.ComponentDropDownLabel_2 = uilabel(app.GridLayout34);
            app.ComponentDropDownLabel_2.Text = 'Component';
            app.ComponentDropDown = uidropdown(app.GridLayout34);
            app.ComponentDropDown.Items = {};
            app.ComponentDropDown.Editable = 'on';
            app.ComponentDropDown.Value = {};
            app.UITable4 = uitable(app.GridLayout34);
            app.UITable4.ColumnName = {'Component'; 'Mol Fraction'};
            app.UITable4.RowName = {};
            app.PhaseDropDownLabel = uilabel(app.GridLayout34);
            app.PhaseDropDownLabel.Text = 'Phase';
            app.PhaseDropDown = uidropdown(app.GridLayout34);
            app.PhaseDropDown.Items = {'Liquid', 'Gas'};
            app.PhaseDropDown.Value = 'Liquid';
            app.EditField_8 = uieditfield(app.GridLayout34, 'text');
            app.MolFractionLabel = uilabel(app.GridLayout34);
            app.MolFractionLabel.Text = 'Mol Fraction';
            app.AddButton_3 = uibutton(app.GridLayout34, 'push');
            app.AddButton_3.Text = 'Add';
            app.RemoveButton_4 = uibutton(app.GridLayout34, 'push');
            app.RemoveButton_4.Text = 'Remove';
            app.LiquidGasMassTransferPanel = uipanel(app.GridLayout27);
            app.LiquidGasMassTransferPanel.Title = 'Liquid-Gas Mass Transfer';
            app.GridLayout35 = uigridlayout(app.LiquidGasMassTransferPanel);
            app.ComponentDropDown_2Label_2 = uilabel(app.GridLayout35);
            app.ComponentDropDown_2Label_2.Text = 'Component';
            app.ComponentDropDown_2 = uidropdown(app.GridLayout35);
            app.UITable5 = uitable(app.GridLayout35);
            app.UITable5.ColumnName = {'Component'; 'Henry''s Law Constant'; 'Units'};
            app.UITable5.RowName = {};
            app.GoverningFunctionsTab = uitab(app.TabGroup);
            app.GoverningFunctionsTab.Title = 'Governing Functions';
            app.GridLayout = uigridlayout(app.GoverningFunctionsTab);
            app.ComponentDropDownLabel = uilabel(app.GridLayout);
            app.ComponentDropDownLabel.Text = 'Component';
            app.InteractCompDD = uidropdown(app.GridLayout);
            app.InteractCompDD.Items = {};
            app.InteractCompDD.Editable = 'on';
            app.InteractCompDD.ValueChangedFcn = createCallbackFcn(app, @InteractCompDDValueChanged, true);
            app.InteractCompDD.Value = {};
            app.GovFuncPanel = uipanel(app.GridLayout);
            app.GovFuncPanel.Title = 'Governing Function: ';
            app.GridLayout18 = uigridlayout(app.GovFuncPanel);
            app.GovFuncL = uilabel(app.GridLayout18);
            app.GovFuncL.Interpreter = 'latex';
            app.GovFuncL.Text = '';
            app.SaveL = uilabel(app.GridLayout18);
            app.SaveL.Interpreter = 'latex';
            app.SaveL.Text = 'Saved';
            app.SysVarT = uitable(app.GridLayout);
            app.SysVarT.ColumnName = {'System Variable'; 'Expression'};
            app.SysVarT.RowName = {};
            app.FuncDispB = uibutton(app.GridLayout, 'state');
            app.FuncDispB.ValueChangedFcn = createCallbackFcn(app, @FuncDispBValueChanged, true);
            app.FuncDispB.Text = 'Function Names';
            app.ModelFullyDefinedNoWarningsButton = uibutton(app.GridLayout, 'push');
            app.ModelFullyDefinedNoWarningsButton.Text = 'Model Fully Defined, No Warnings :)';
            app.SubFuncPanel = uipanel(app.GridLayout);
            app.SubFuncPanel.Title = 'Add/Edit Sub Functions';
            app.GridLayout19 = uigridlayout(app.SubFuncPanel);
            app.FunctionTypeDropDown_2Label = uilabel(app.GridLayout19);
            app.FunctionTypeDropDown_2Label.Text = 'Function Type';
            app.FuncComboDD = uidropdown(app.GridLayout19);
            app.FuncComboDD.Items = {'Multiplicative', 'Additive'};
            app.FuncComboDD.ValueChangedFcn = createCallbackFcn(app, @FuncComboDDValueChanged, true);
            app.FuncComboDD.Value = 'Multiplicative';
            app.FuncTypeDD = uidropdown(app.GridLayout19);
            app.FuncTypeDD.Items = {'Monod', 'Linear', 'Custom'};
            app.FuncTypeDD.Editable = 'on';
            app.FuncTypeDD.Value = 'Monod';
            app.FuncNewAB = uibutton(app.GridLayout19, 'push');
            app.FuncNewAB.ButtonPushedFcn = createCallbackFcn(app, @FuncNewABButtonPushed, true);
            app.FuncNewAB.Text = 'Add New';
            app.ModelRB = uibutton(app.GridLayout19, 'push');
            app.ModelRB.ButtonPushedFcn = createCallbackFcn(app, @ModelRBButtonPushed, true);
            app.ModelRB.Enable = 'off';
            app.ModelRB.Text = 'Remove';
            app.ModelUB = uibutton(app.GridLayout19, 'push');
            app.ModelUB.ButtonPushedFcn = createCallbackFcn(app, @ModelUBButtonPushed, true);
            app.ModelUB.Enable = 'off';
            app.ModelUB.Text = 'Update';
            app.SubFunctionDropDownLabel = uilabel(app.GridLayout19);
            app.SubFunctionDropDownLabel.Enable = 'off';
            app.SubFunctionDropDownLabel.Text = 'Sub Function';
            app.SubFuncDD = uidropdown(app.GridLayout19);
            app.SubFuncDD.Items = {};
            app.SubFuncDD.Editable = 'on';
            app.SubFuncDD.ValueChangedFcn = createCallbackFcn(app, @SubFuncDDValueChanged, true);
            app.SubFuncDD.Enable = 'off';
            app.SubFuncDD.Value = {};
            app.SubFuncVF = uieditfield(app.GridLayout19, 'text');
            app.SubFuncVF.ValueChangingFcn = createCallbackFcn(app, @SubFuncVFValueChanging, true);
            app.SubFuncVF.Enable = 'off';
            app.SubFuncNF = uieditfield(app.GridLayout19, 'text');
            app.SubFuncNF.ValueChangingFcn = createCallbackFcn(app, @SubFuncNFValueChanging, true);
            app.SubFuncNF.Enable = 'off';
            app.EqualsL = uilabel(app.GridLayout19);
            app.EqualsL.Text = '=';
            app.PiecewiseLimsP = uipanel(app.GridLayout);
            app.PiecewiseLimsP.Title = 'Set Function Limits';
            app.GridLayout20 = uigridlayout(app.PiecewiseLimsP);
            app.LimitsLabel = uilabel(app.GridLayout20);
            app.LimitsLabel.Enable = 'off';
            app.LimitsLabel.Text = 'Limits';
            app.PiecewiseRangeLabel = uilabel(app.GridLayout20);
            app.PiecewiseRangeLabel.Enable = 'off';
            app.PiecewiseRangeLabel.Text = '=';
            app.EditField_4 = uieditfield(app.GridLayout20, 'text');
            app.EditField_4.Enable = 'off';
            app.Label_2 = uilabel(app.GridLayout20);
            app.Label_2.Enable = 'off';
            app.Label_2.Text = '<';
            app.DropDown = uidropdown(app.GridLayout20);
            app.DropDown.Items = {'t', 'Option 2', 'Option 3', 'Option 4'};
            app.DropDown.Editable = 'on';
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 't';
            app.Label_3 = uilabel(app.GridLayout20);
            app.Label_3.Enable = 'off';
            app.Label_3.Text = '<';
            app.EditField_5 = uieditfield(app.GridLayout20, 'text');
            app.EditField_5.Enable = 'off';
            app.UITable3 = uitable(app.GridLayout20);
            app.UITable3.ColumnName = {'Lower Bound'; 'Variable'; 'Upper Bound'};
            app.UITable3.RowName = {};
            app.UITable3.Enable = 'off';
            app.AddButton_2 = uibutton(app.GridLayout20, 'push');
            app.AddButton_2.Enable = 'off';
            app.AddButton_2.Text = 'Add';
            app.RemoveButton_3 = uibutton(app.GridLayout20, 'push');
            app.RemoveButton_3.Enable = 'off';
            app.RemoveButton_3.Text = 'Remove';
            app.HelperFuncP = uipanel(app.GridLayout);
            app.HelperFuncP.Title = 'Helper Functions';
            app.GridLayout21 = uigridlayout(app.HelperFuncP);
            app.HelperDD = uidropdown(app.GridLayout21);
            app.HelperDD.Items = {};
            app.HelperDD.Editable = 'on';
            app.HelperDD.ValueChangedFcn = createCallbackFcn(app, @HelperDDValueChanged, true);
            app.HelperDD.Value = {};
            app.HelperUB = uibutton(app.GridLayout21, 'push');
            app.HelperUB.ButtonPushedFcn = createCallbackFcn(app, @HelperUBButtonPushed, true);
            app.HelperUB.Enable = 'off';
            app.HelperUB.Text = 'Update';
            app.HelperRB = uibutton(app.GridLayout21, 'push');
            app.HelperRB.ButtonPushedFcn = createCallbackFcn(app, @HelperRBButtonPushed, true);
            app.HelperRB.Text = 'Remove';
            app.Label = uilabel(app.GridLayout21);
            app.Label.Text = '=';
            app.HelperValF = uieditfield(app.GridLayout21, 'text');
            app.HelperValF.ValueChangingFcn = createCallbackFcn(app, @HelperValFValueChanging, true);
            app.HelperSymF = uieditfield(app.GridLayout21, 'text');
            app.HelperSymF.ValueChangingFcn = createCallbackFcn(app, @HelperSymFValueChanging, true);
            app.HelperNameF = uieditfield(app.GridLayout21, 'text');
            app.HelperNameF.ValueChangingFcn = createCallbackFcn(app, @HelperNameFValueChanging, true);
            app.Label_10 = uilabel(app.GridLayout21);
            app.Label_10.Interpreter = 'latex';
            app.Label_10.Text = ':';
            app.HelperAB = uibutton(app.GridLayout21, 'push');
            app.HelperAB.ButtonPushedFcn = createCallbackFcn(app, @HelperABButtonPushed, true);
            app.HelperAB.Text = 'Add New';
            app.HelperFuncL = uilabel(app.GridLayout21);
            app.HelperFuncL.Interpreter = 'latex';
            app.HelperFuncL.Text = '';
            app.HelperSaveL = uilabel(app.GridLayout21);
            app.HelperSaveL.Interpreter = 'latex';
            app.HelperSaveL.Text = 'Saved';
            app.ParametersTab = uitab(app.TabGroup);
            app.ParametersTab.Title = 'Parameters';
            app.GridLayout7 = uigridlayout(app.ParametersTab);
            app.GrthParamT = uitable(app.GridLayout7);
            app.GrthParamT.ColumnName = {'Number'; 'Function'; 'Symbol'; 'Name'; 'Value'; 'Unit'};
            app.GrthParamT.RowName = {};
            app.GrthParamT.ColumnSortable = true;
            app.ParameterNumberLabel = uilabel(app.GridLayout7);
            app.ParameterNumberLabel.Text = 'Parameter Number';
            app.ParamNumDD = uidropdown(app.GridLayout7);
            app.ParamNumDD.Items = {};
            app.ParamNumDD.Editable = 'on';
            app.ParamNumDD.ValueChangedFcn = createCallbackFcn(app, @ParamNumDDValueChanged, true);
            app.ParamNumDD.Value = {};
            app.ParamValF = uieditfield(app.GridLayout7, 'numeric');
            app.ParamUnitDD = uidropdown(app.GridLayout7);
            app.ParamUnitDD.Items = {'', 'g/L'};
            app.ParamUnitDD.Editable = 'on';
            app.ParamUnitDD.Value = 'g/L';
            app.ParamUB = uibutton(app.GridLayout7, 'push');
            app.ParamUB.ButtonPushedFcn = createCallbackFcn(app, @ParamUBPushed, true);
            app.ParamUB.Text = 'Update';
            app.SpeciesChemicalLabel = uilabel(app.GridLayout7);
            app.SpeciesChemicalLabel.Text = 'Species/Chemical';
            app.CompParamDD = uidropdown(app.GridLayout7);
            app.CompParamDD.Items = {};
            app.CompParamDD.Editable = 'on';
            app.CompParamDD.ValueChangedFcn = createCallbackFcn(app, @CompParamDDValueChanged, true);
            app.CompParamDD.Value = {};
            app.ParameterNameEditFieldLabel = uilabel(app.GridLayout7);
            app.ParameterNameEditFieldLabel.Text = 'Parameter Name';
            app.ParamNF = uieditfield(app.GridLayout7, 'text');
            app.ParameterSymbolDropDownLabel = uilabel(app.GridLayout7);
            app.ParameterSymbolDropDownLabel.Text = 'Parameter Symbol';
            app.ParamSymDD = uidropdown(app.GridLayout7);
            app.ParamSymDD.Items = {};
            app.ParamSymDD.Editable = 'on';
            app.ParamSymDD.ValueChangedFcn = createCallbackFcn(app, @ParamSymDDValueChanged, true);
            app.ParamSymDD.Value = {};
            app.RegressionTab = uitab(app.TabGroup);
            app.RegressionTab.Title = 'Regression';
            app.GridLayout32 = uigridlayout(app.RegressionTab);
            app.GridLayout32.Tag = 'unmatch';
            app.RegPlot = uiaxes(app.GridLayout32);
            title(app.RegPlot, 'Regression')
            xlabel(app.RegPlot, 'Time')
            ylabel(app.RegPlot, 'Dependent Variable(s)')
            zlabel(app.RegPlot, 'Z')
            app.RegParamDD = uidropdown(app.GridLayout32);
            app.RegParamDD.Items = {};
            app.RegParamDD.Editable = 'on';
            app.RegParamDD.Value = {};
            app.SaveRegParamB = uibutton(app.GridLayout32, 'push');
            app.SaveRegParamB.ButtonPushedFcn = createCallbackFcn(app, @SaveRegParamBButtonPushed, true);
            app.SaveRegParamB.Text = 'Save';
            app.ParametersDropDownLabel = uilabel(app.GridLayout32);
            app.ParametersDropDownLabel.Text = 'Parameters';
            app.RegParamAB = uibutton(app.GridLayout32, 'push');
            app.RegParamAB.ButtonPushedFcn = createCallbackFcn(app, @RegParamUpdate, true);
            app.RegParamAB.Tag = 'Add';
            app.RegParamAB.Text = 'Add';
            app.RegParamRB = uibutton(app.GridLayout32, 'push');
            app.RegParamRB.ButtonPushedFcn = createCallbackFcn(app, @RegParamUpdate, true);
            app.RegParamRB.Tag = 'Remove';
            app.RegParamRB.Text = 'Remove';
            app.SystemVariableDropDownLabel = uilabel(app.GridLayout32);
            app.SystemVariableDropDownLabel.Text = 'System Variable';
            app.RegSysVarDD = uidropdown(app.GridLayout32);
            app.RegSysVarDD.Items = {};
            app.RegSysVarDD.Editable = 'on';
            app.RegSysVarDD.Value = {};
            app.ImportedDataDropDownLabel = uilabel(app.GridLayout32);
            app.ImportedDataDropDownLabel.Text = 'Imported Data';
            app.ImportedDataDD = uidropdown(app.GridLayout32);
            app.ImportedDataDD.Items = {};
            app.ImportedDataDD.Editable = 'on';
            app.ImportedDataDD.Value = {};
            app.VarDataMatchB = uibutton(app.GridLayout32, 'push');
            app.VarDataMatchB.ButtonPushedFcn = createCallbackFcn(app, @UpdateDVDataMatching, true);
            app.VarDataMatchB.Tag = 'match';
            app.VarDataMatchB.Text = 'Match';
            app.RegParamT = uitable(app.GridLayout32);
            app.RegParamT.ColumnName = {'Parameter'; 'Component'; 'Function'; 'Value'; 'Regressed Value'};
            app.RegParamT.RowName = {};
            app.RunRegB = uibutton(app.GridLayout32, 'push');
            app.RunRegB.ButtonPushedFcn = createCallbackFcn(app, @RunRegBPushed, true);
            app.RunRegB.Enable = 'off';
            app.RunRegB.Text = 'Run Regression';
            app.ParameterDropDownLabel_2 = uilabel(app.GridLayout32);
            app.ParameterDropDownLabel_2.Text = 'Parameter';
            app.SaveRegParamDD = uidropdown(app.GridLayout32);
            app.SaveRegParamDD.Items = {};
            app.SaveRegParamDD.Editable = 'on';
            app.SaveRegParamDD.Value = {};
            app.SaveAllRegParamB = uibutton(app.GridLayout32, 'push');
            app.SaveAllRegParamB.ButtonPushedFcn = createCallbackFcn(app, @SaveAllRegParamBButtonPushed, true);
            app.SaveAllRegParamB.Text = 'Save All';
            app.ImportDataB = uibutton(app.GridLayout32, 'push');
            app.ImportDataB.ButtonPushedFcn = createCallbackFcn(app, @ImportBButtonPushed, true);
            app.ImportDataB.Text = 'Import Data';
            app.DependentVariableLabel = uilabel(app.GridLayout32);
            app.DependentVariableLabel.Text = 'Dependent Variable';
            app.RegDVDD = uidropdown(app.GridLayout32);
            app.RegDVDD.Items = {};
            app.RegDVDD.Editable = 'on';
            app.RegDVDD.Value = {};
            app.RegDVAB = uibutton(app.GridLayout32, 'push');
            app.RegDVAB.ButtonPushedFcn = createCallbackFcn(app, @RegDVPlotUpdate, true);
            app.RegDVAB.Tag = 'add';
            app.RegDVAB.Text = 'Plot';
            app.RegVarDataT = uitable(app.GridLayout32);
            app.RegVarDataT.ColumnName = {'System Variable Name'; 'Imported Variable Name'};
            app.RegVarDataT.RowName = {};
            app.ComponentDropDown_2Label = uilabel(app.GridLayout32);
            app.ComponentDropDown_2Label.Text = 'Component';
            app.RegCompParamDD = uidropdown(app.GridLayout32);
            app.RegCompParamDD.Items = {};
            app.RegCompParamDD.Editable = 'on';
            app.RegCompParamDD.ValueChangedFcn = createCallbackFcn(app, @RegCompParamDDValueChanged, true);
            app.RegCompParamDD.Value = {};
            app.VarDataUnmatchB = uibutton(app.GridLayout32, 'push');
            app.VarDataUnmatchB.ButtonPushedFcn = createCallbackFcn(app, @UpdateDVDataMatching, true);
            app.VarDataUnmatchB.Text = 'Unmatch';
            app.RegSpecsB = uibutton(app.GridLayout32, 'push');
            app.RegSpecsB.ButtonPushedFcn = createCallbackFcn(app, @RegSpecsBButtonPushed, true);
            app.RegSpecsB.Enable = 'off';
            app.RegSpecsB.Text = 'Regression Specifications';
            app.RegAnalysisB = uibutton(app.GridLayout32, 'push');
            app.RegAnalysisB.ButtonPushedFcn = createCallbackFcn(app, @RegAnalysisBPushed, true);
            app.RegAnalysisB.Enable = 'off';
            app.RegAnalysisB.Text = 'Goodness of Fit Analysis';
            app.PlotCustomizationTab = uitab(app.TabGroup);
            app.PlotCustomizationTab.Title = 'Plot Customization';
            app.GridLayout4 = uigridlayout(app.PlotCustomizationTab);
            app.PlotsDropDownLabel = uilabel(app.GridLayout4);
            app.PlotsDropDownLabel.Text = 'Plots';
            app.PlotTitleDD = uidropdown(app.GridLayout4);
            app.PlotTitleDD.Items = {};
            app.PlotTitleDD.Editable = 'on';
            app.PlotTitleDD.ValueChangedFcn = createCallbackFcn(app, @PlotTitleDDValueChanged, true);
            app.PlotTitleDD.Value = {};
            app.PlotRB = uibutton(app.GridLayout4, 'push');
            app.PlotRB.ButtonPushedFcn = createCallbackFcn(app, @PlotRBButtonPushed, true);
            app.PlotRB.Text = 'Remove';
            app.AxisSpecificationsP = uipanel(app.GridLayout4);
            app.AxisSpecificationsP.Title = 'Axis Specifications';
            app.GridLayout22 = uigridlayout(app.AxisSpecificationsP);
            app.XAxisTitleLabel = uilabel(app.GridLayout22);
            app.XAxisTitleLabel.Text = 'X Axis Title';
            app.XAxTitleF = uieditfield(app.GridLayout22, 'text');
            app.XAxTitleF.ValueChangedFcn = createCallbackFcn(app, @AxTitleUpdate, true);
            app.XAxTitleF.Tag = 'X';
            app.YAxisTitleLabel = uilabel(app.GridLayout22);
            app.YAxisTitleLabel.Text = 'Y Axis Title';
            app.YAxTitleF = uieditfield(app.GridLayout22, 'text');
            app.YAxTitleF.ValueChangedFcn = createCallbackFcn(app, @AxTitleUpdate, true);
            app.YAxTitleF.Tag = 'Y';
            app.ZAxisTitleEditFieldLabel = uilabel(app.GridLayout22);
            app.ZAxisTitleEditFieldLabel.Text = 'Z-Axis Title';
            app.ZAxTitleF = uieditfield(app.GridLayout22, 'text');
            app.ZAxTitleF.ValueChangedFcn = createCallbackFcn(app, @AxTitleUpdate, true);
            app.ZAxTitleF.Tag = 'Z';
            app.XAxDefaultRCB = uicheckbox(app.GridLayout22);
            app.XAxDefaultRCB.Text = 'Use Default Range';
            app.NumberofAxesDropDownLabel = uilabel(app.GridLayout22);
            app.NumberofAxesDropDownLabel.Text = 'Number of Axes';
            app.NbAxesDD = uidropdown(app.GridLayout22);
            app.NbAxesDD.Items = {'2-Axis', '3-Axis'};
            app.NbAxesDD.ValueChangedFcn = createCallbackFcn(app, @PlotTitleAxUBButtonPushed, true);
            app.NbAxesDD.Value = '2-Axis';
            app.VariableDropDownLabel = uilabel(app.GridLayout22);
            app.VariableDropDownLabel.Text = 'Variable';
            app.XAxVarDD = uidropdown(app.GridLayout22);
            app.XAxVarDD.Items = {};
            app.XAxVarDD.Editable = 'on';
            app.XAxVarDD.Value = {};
            app.VariableDropDown_2Label = uilabel(app.GridLayout22);
            app.VariableDropDown_2Label.Text = 'Variable';
            app.YAxVarDD = uidropdown(app.GridLayout22);
            app.YAxVarDD.Items = {};
            app.YAxVarDD.Editable = 'on';
            app.YAxVarDD.Value = {};
            app.YAxDefaultRCB = uicheckbox(app.GridLayout22);
            app.YAxDefaultRCB.Text = 'Use Default Range';
            app.VariableDropDown_3Label = uilabel(app.GridLayout22);
            app.VariableDropDown_3Label.Text = 'Variable';
            app.ZAxVarDD = uidropdown(app.GridLayout22);
            app.ZAxVarDD.Items = {};
            app.ZAxVarDD.Editable = 'on';
            app.ZAxVarDD.Value = {};
            app.ZAxDefaultRCB = uicheckbox(app.GridLayout22);
            app.ZAxDefaultRCB.Text = 'Use Default Range';
            app.LowerDisplayLimitDropDown_3Label = uilabel(app.GridLayout22);
            app.LowerDisplayLimitDropDown_3Label.Text = 'Lower Display Limit';
            app.XAxLoDispLimDD = uidropdown(app.GridLayout22);
            app.XAxLoDispLimDD.Items = {};
            app.XAxLoDispLimDD.Editable = 'on';
            app.XAxLoDispLimDD.Value = {};
            app.UpperDisplayLimitDropDown_3Label = uilabel(app.GridLayout22);
            app.UpperDisplayLimitDropDown_3Label.Text = 'Upper Display Limit';
            app.XAxUpDispLimDD = uidropdown(app.GridLayout22);
            app.XAxUpDispLimDD.Items = {};
            app.XAxUpDispLimDD.Editable = 'on';
            app.XAxUpDispLimDD.Value = {};
            app.LowerDisplayLimitDropDown_2Label = uilabel(app.GridLayout22);
            app.LowerDisplayLimitDropDown_2Label.Text = 'Lower Display Limit';
            app.YAxLoDispLimDD = uidropdown(app.GridLayout22);
            app.YAxLoDispLimDD.Items = {};
            app.YAxLoDispLimDD.Editable = 'on';
            app.YAxLoDispLimDD.Value = {};
            app.UpperDisplayLimitDropDown_2Label = uilabel(app.GridLayout22);
            app.UpperDisplayLimitDropDown_2Label.Text = 'Upper Display Limit';
            app.YAxUpDispLimDD = uidropdown(app.GridLayout22);
            app.YAxUpDispLimDD.Items = {};
            app.YAxUpDispLimDD.Editable = 'on';
            app.YAxUpDispLimDD.Value = {};
            app.LowerDisplayLimitDropDown_4Label = uilabel(app.GridLayout22);
            app.LowerDisplayLimitDropDown_4Label.Text = 'Lower Display Limit';
            app.ZAxLoDispLimDD = uidropdown(app.GridLayout22);
            app.ZAxLoDispLimDD.Items = {};
            app.ZAxLoDispLimDD.Editable = 'on';
            app.ZAxLoDispLimDD.Value = {};
            app.UpperDisplayLimitDropDown_4Label = uilabel(app.GridLayout22);
            app.UpperDisplayLimitDropDown_4Label.Text = 'Upper Display Limit';
            app.ZAxUpDispLimDD = uidropdown(app.GridLayout22);
            app.ZAxUpDispLimDD.Items = {};
            app.ZAxUpDispLimDD.Editable = 'on';
            app.ZAxUpDispLimDD.Value = {};
            app.Div3 = uilabel(app.GridLayout22);
            app.Div3.Text = '';
            app.Div2 = uilabel(app.GridLayout22);
            app.Div2.Text = '';
            app.Div1 = uilabel(app.GridLayout22);
            app.Div1.Text = '';
            app.XAxDispLimUB = uibutton(app.GridLayout22, 'push');
            app.XAxDispLimUB.ButtonPushedFcn = createCallbackFcn(app, @AxDispLimUBButtonPushed, true);
            app.XAxDispLimUB.Tag = 'X';
            app.XAxDispLimUB.Text = 'Update';
            app.YAxDispLimUB = uibutton(app.GridLayout22, 'push');
            app.YAxDispLimUB.ButtonPushedFcn = createCallbackFcn(app, @AxDispLimUBButtonPushed, true);
            app.YAxDispLimUB.Tag = 'Y';
            app.YAxDispLimUB.Text = 'Update';
            app.ZAxDispLimUB = uibutton(app.GridLayout22, 'push');
            app.ZAxDispLimUB.ButtonPushedFcn = createCallbackFcn(app, @AxDispLimUBButtonPushed, true);
            app.ZAxDispLimUB.Tag = 'Z';
            app.ZAxDispLimUB.Text = 'Update';
            app.TitleEditFieldLabel = uilabel(app.GridLayout22);
            app.TitleEditFieldLabel.Text = 'Title';
            app.PlotTitleF = uieditfield(app.GridLayout22, 'text');
            app.PlotTitleF.ValueChangedFcn = createCallbackFcn(app, @PlotTitleAxUBButtonPushed, true);
            app.YAxVarL = uilabel(app.GridLayout22);
            app.YAxVarL.Interpreter = 'latex';
            app.YAxVarL.Text = 'Variable(s): None';
            app.YAxVarAB = uibutton(app.GridLayout22, 'push');
            app.YAxVarAB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.YAxVarAB.Tag = 'Y_add';
            app.YAxVarAB.Text = 'Add';
            app.YAxVarRB = uibutton(app.GridLayout22, 'push');
            app.YAxVarRB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.YAxVarRB.Tag = 'Y_rm';
            app.YAxVarRB.Text = 'Remove';
            app.ZAxVarL = uilabel(app.GridLayout22);
            app.ZAxVarL.Interpreter = 'latex';
            app.ZAxVarL.Text = 'Variable(s): None';
            app.XAxVarL = uilabel(app.GridLayout22);
            app.XAxVarL.Interpreter = 'latex';
            app.XAxVarL.Text = 'Variable(s): None';
            app.XAxVarAB = uibutton(app.GridLayout22, 'push');
            app.XAxVarAB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.XAxVarAB.Tag = 'X_add';
            app.XAxVarAB.Text = 'Add';
            app.XAxVarRB = uibutton(app.GridLayout22, 'push');
            app.XAxVarRB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.XAxVarRB.Tag = 'X_rm';
            app.XAxVarRB.Text = 'Remove';
            app.ZAxVarAB = uibutton(app.GridLayout22, 'push');
            app.ZAxVarAB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.ZAxVarAB.Tag = 'Z_add';
            app.ZAxVarAB.Text = 'Add';
            app.ZAxVarRB = uibutton(app.GridLayout22, 'push');
            app.ZAxVarRB.ButtonPushedFcn = createCallbackFcn(app, @AxVarUBButtonPushed, true);
            app.ZAxVarRB.Tag = 'Z_rm';
            app.ZAxVarRB.Text = 'Remove';
            app.PlotInitCondP = uipanel(app.GridLayout4);
            app.PlotInitCondP.Title = 'Additional Specifications';
            app.GridLayout23 = uigridlayout(app.PlotInitCondP);
            app.SysVarInitCondT = uitable(app.GridLayout23);
            app.SysVarInitCondT.ColumnName = {'Variable'; 'Unit'};
            app.SysVarInitCondT.RowName = {};
            app.SysVarInitCondT.ColumnSortable = true;
            app.SysVarInitCondT.ColumnEditable = true;
            app.AddSpecDD1 = uidropdown(app.GridLayout23);
            app.AddSpecDD1.Items = {};
            app.AddSpecDD1.Editable = 'on';
            app.AddSpecDD1.Visible = 'off';
            app.AddSpecDD1.Value = {};
            app.AddSpecDD2 = uidropdown(app.GridLayout23);
            app.AddSpecDD2.Items = {};
            app.AddSpecDD2.Editable = 'on';
            app.AddSpecDD2.Visible = 'off';
            app.AddSpecDD2.Value = {};
            app.PlotICEvalSpecUB = uibutton(app.GridLayout23, 'push');
            app.PlotICEvalSpecUB.ButtonPushedFcn = createCallbackFcn(app, @PlotICEvalSpecUBButtonPushed, true);
            app.PlotICEvalSpecUB.Text = 'Update';
            app.AxisDropDownLabel = uilabel(app.GridLayout23);
            app.AxisDropDownLabel.Text = 'Axis';
            app.ICEvalAxisDD = uidropdown(app.GridLayout23);
            app.ICEvalAxisDD.Items = {};
            app.ICEvalAxisDD.Editable = 'on';
            app.ICEvalAxisDD.ValueChangedFcn = createCallbackFcn(app, @ICEvalAxisDDValueChanged, true);
            app.ICEvalAxisDD.Value = {};
            app.AddSpecDD3 = uidropdown(app.GridLayout23);
            app.AddSpecDD3.Items = {};
            app.AddSpecDD3.Editable = 'on';
            app.AddSpecDD3.Tag = 'Z';
            app.AddSpecDD3.Visible = 'off';
            app.AddSpecDD3.Value = {};
            app.VariableDropDownLabel_2 = uilabel(app.GridLayout23);
            app.VariableDropDownLabel_2.Text = 'Variable';
            app.ICEvalVarDD = uidropdown(app.GridLayout23);
            app.ICEvalVarDD.Items = {};
            app.ICEvalVarDD.Editable = 'on';
            app.ICEvalVarDD.ValueChangedFcn = createCallbackFcn(app, @ICEvalVarDDValueChanged, true);
            app.ICEvalVarDD.Value = {};
            app.UnitsLabel = uilabel(app.GridLayout23);
            app.UnitsLabel.Text = 'Units';
            app.evalUnitsDD = uidropdown(app.GridLayout23);
            app.evalUnitsDD.Items = {};
            app.evalUnitsDD.Editable = 'on';
            app.evalUnitsDD.Value = {};
            app.AddSpecL1 = uilabel(app.GridLayout23);
            app.AddSpecL1.Visible = 'off';
            app.AddSpecL1.Text = '';
            app.AddSpecL2 = uilabel(app.GridLayout23);
            app.AddSpecL2.Visible = 'off';
            app.AddSpecL2.Text = '';
            app.AddSpecL3 = uilabel(app.GridLayout23);
            app.AddSpecL3.Visible = 'off';
            app.AddSpecL3.Text = 'Number of Evaluation Points (#Pts):';
            app.PlotGenerationP = uipanel(app.GridLayout4);
            app.PlotGenerationP.Title = 'Model Compilation';
            app.GridLayout24 = uigridlayout(app.PlotGenerationP);
            app.GenPlotB = uibutton(app.GridLayout24, 'push');
            app.GenPlotB.ButtonPushedFcn = createCallbackFcn(app, @GenPlotBButtonPushed, true);
            app.GenPlotB.Text = 'Compile Model';
            app.OptionsL = uilabel(app.GridLayout24);
            app.OptionsL.Text = 'Options';
            app.ClosePrevPlotsCB = uicheckbox(app.GridLayout24);
            app.ClosePrevPlotsCB.Text = 'Close Previous Plots';
            app.SelectPlotsL = uilabel(app.GridLayout24);
            app.SelectPlotsL.Text = 'Select Plots:';
            app.PlotDispTree = uitree(app.GridLayout24, 'checkbox');
            app.PlotDispTree.Editable = 'on';
            % Assign Checked Nodes
            app.PlotDispTree.CheckedNodesChangedFcn = createCallbackFcn(app, @PlotDispTreeCheckedNodesChanged, true);
            app.PlotDownloadP = uipanel(app.GridLayout4);
            app.PlotDownloadP.Title = 'Plot Download';
            app.GridLayout25 = uigridlayout(app.PlotDownloadP);
            app.PlotDirL = uilabel(app.GridLayout25);
            app.PlotDirL.Text = 'Directory: None Selected';
            app.SelectDirectoryB = uibutton(app.GridLayout25, 'push');
            app.SelectDirectoryB.ButtonPushedFcn = createCallbackFcn(app, @SelectDirectoryBButtonPushed, true);
            app.SelectDirectoryB.Text = 'Select Directory';
            app.FileTypeDropDownLabel = uilabel(app.GridLayout25);
            app.FileTypeDropDownLabel.Text = 'File Type';
            app.FileTypeDD = uidropdown(app.GridLayout25);
            app.FileTypeDD.Items = {'.png', '.jpg', '.jpeg', '.fig'};
            app.FileTypeDD.Value = '.png';
            app.PlotAB = uibutton(app.GridLayout4, 'push');
            app.PlotAB.ButtonPushedFcn = createCallbackFcn(app, @PlotABButtonPushed, true);
            app.PlotAB.Text = 'Add';
            app.ModelSummaryTab_3 = uitab(app.TabGroup);
            app.ModelSummaryTab_3.Title = 'Model Summary';
            app.GridLayout15 = uigridlayout(app.ModelSummaryTab_3);
            app.GoverningFunctionsPanel = uipanel(app.GridLayout15);
            app.GoverningFunctionsPanel.Title = 'Governing Functions';
            app.GridLayout26 = uigridlayout(app.GoverningFunctionsPanel);
            app.MSumL = uilabel(app.GridLayout26);
            app.MSumL.Interpreter = 'latex';
            app.MSumL.Text = '';
            app.SpecChemToggleB = uibutton(app.GridLayout26, 'state');
            app.SpecChemToggleB.ValueChangedFcn = createCallbackFcn(app, @SpecChemToggleBValueChanged, true);
            app.SpecChemToggleB.Text = 'Species';
            app.CompileModelButton = uibutton(app.GridLayout26, 'push');
            app.CompileModelButton.Text = 'Compile Model';
            app.ModelNotCompiledLabel = uilabel(app.GridLayout26);
            app.ModelNotCompiledLabel.Text = 'Model Not Compiled';
            app.ContextMenu2 = uicontextmenu(app.MAGMAUIFigure);
            app.Menu = uimenu(app.ContextMenu2);
            app.Menu.Text = 'Menu';
            app.Menu2_2 = uimenu(app.ContextMenu2);
            app.Menu2_2.Text = 'Menu2';
            % Show the figure after all components are created
            app.MAGMAUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MAGMA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MAGMAUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MAGMAUIFigure)
        end
    end
end